classdef Descriptive_Statistic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DescriptiveStatistic      matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        DescriptiveStatisticTab   matlab.ui.container.Tab
        GridLayout                matlab.ui.container.GridLayout
        GridLayout3               matlab.ui.container.GridLayout
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes_5                  matlab.ui.control.UIAxes
        UIAxes_8                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_7                  matlab.ui.control.UIAxes
        UIAxes_10                 matlab.ui.control.UIAxes
        UIAxes_6                  matlab.ui.control.UIAxes
        UIAxes_9                  matlab.ui.control.UIAxes
        UIAxes_1                  matlab.ui.control.UIAxes
        GridLayout4               matlab.ui.container.GridLayout
        DateDropDownLabel         matlab.ui.control.Label
        DateDropDown              matlab.ui.control.DropDown
        PreviousButton            matlab.ui.control.Button
        NextButton                matlab.ui.control.Button
        Switch                    matlab.ui.control.Switch
        UnitsDropDownLabel        matlab.ui.control.Label
        UnitsDropDown             matlab.ui.control.DropDown
        AddUnitsLabel             matlab.ui.control.Label
        AddUnitsEditField         matlab.ui.control.EditField
        AddButton                 matlab.ui.control.Button
        WeekdayLabel              matlab.ui.control.Label
        YearDropDownLabel         matlab.ui.control.Label
        YearDropDown              matlab.ui.control.DropDown
        SignalLengthLabel         matlab.ui.control.Label
        SignalLengthDropDown      matlab.ui.control.DropDown
        PlotsofStatisticsTab      matlab.ui.container.Tab
        GridLayout_2              matlab.ui.container.GridLayout
        GridLayout3_2             matlab.ui.container.GridLayout
        UIAxes_12                 matlab.ui.control.UIAxes
        UIAxes_13                 matlab.ui.control.UIAxes
        UIAxes_14                 matlab.ui.control.UIAxes
        UIAxes_15                 matlab.ui.control.UIAxes
        UIAxes_16                 matlab.ui.control.UIAxes
        UIAxes_17                 matlab.ui.control.UIAxes
        UIAxes_18                 matlab.ui.control.UIAxes
        UIAxes_19                 matlab.ui.control.UIAxes
        UIAxes_20                 matlab.ui.control.UIAxes
        UIAxes_11                 matlab.ui.control.UIAxes
        GridLayout4_2             matlab.ui.container.GridLayout
        DateDropDown_2Label       matlab.ui.control.Label
        DateDropDown_2            matlab.ui.control.DropDown
        PreviousButton_2          matlab.ui.control.Button
        NextButton_2              matlab.ui.control.Button
        UnitsDropDown_2Label      matlab.ui.control.Label
        UnitsDropDown_2           matlab.ui.control.DropDown
        AddUnitsEditField_2Label  matlab.ui.control.Label
        AddUnitsEditField_2       matlab.ui.control.EditField
        AddButton_2               matlab.ui.control.Button
        WeekdayLabel_2            matlab.ui.control.Label
        Switch_2                  matlab.ui.control.Switch
        YearDropDown_2Label       matlab.ui.control.Label
        YearDropDown_2            matlab.ui.control.DropDown
        SignalLengthLabel_2       matlab.ui.control.Label
        SignalLengthDropDown_2    matlab.ui.control.DropDown
    end

    
    % Application Properties.
    properties (Access = private)
        Values_1        % Časová tabuľka čistých údajov pre záložku Descriptive Statistics.
        Values_2        % Časová tabuľka čistých údajov pre záložku Plots of Statistics.
        Stats_1         % Tabuľka štatistických parametrov pre záložku Descriptive Statistics.
        Stats_2         % Tabuľka štatistických parametrov pre záložku Plots of Statistics.
        Palette         % Paleta farieb pre vykreslené krivky.
        Interval_Length % Dĺžka intervalov v sekundách.
        Signal_Lengths  % List dĺžok intervalov v minútach.
        Years           % List rokov.
        CurrentYear     % Zvolený rok.
    end
    
    % Application Methods.
    methods (Access = private)    
        
        % DESCRIPTIVE STATISTICS (TAB_1)
        function ds_objects_visibility(app, input_boolean)
            OBJECTS_VE = [
                app.YearDropDown;
                app.YearDropDownLabel;
                app.DateDropDown;
                app.DateDropDownLabel;
                app.PreviousButton;
                app.NextButton;
                app.UnitsDropDown; 
                app.UnitsDropDownLabel;
                app.AddUnitsEditField; 
                app.AddUnitsLabel;
                app.Switch;
                app.WeekdayLabel;
            ];
            OBJECTS_V = [
                app.AddButton;
                app.UIAxes_1;
                app.UIAxes_2;
                app.UIAxes_3;
                app.UIAxes_4;
                app.UIAxes_5;
                app.UIAxes_6;
                app.UIAxes_7;
                app.UIAxes_8;
                app.UIAxes_9;
                app.UIAxes_10;
            ];
            set(OBJECTS_VE, 'Visible', input_boolean);
            set(OBJECTS_VE, 'Enable', input_boolean);
            set(OBJECTS_V, 'Visible', input_boolean);
        end
        
        function ds_gui_visibility(app, input_boolean)
            if ~isempty(app.UIAxes_1.Children)
                set(app.UIAxes_1.Children, 'Visible', input_boolean);
                set(app.UIAxes_4.Children, 'Visible', input_boolean);
                set(app.UIAxes_8.Children, 'Visible', input_boolean);
                set(app.UIAxes_10.Children, 'Visible', input_boolean);
                for i = 1:10
                    if i <= 3
                        set(app.UIAxes_2.Children(i), 'Visible', input_boolean);
                        set(app.UIAxes_3.Children(i), 'Visible', input_boolean);
                        set(app.UIAxes_5.Children(i), 'Visible', input_boolean);
                    end
                    if i <= 2
                        set(app.UIAxes_6.Children(i), 'Visible', input_boolean);
                        set(app.UIAxes_7.Children(i), 'Visible', input_boolean);
                        set(app.UIAxes_9.Children(i), 'Visible', input_boolean);
                    end            
                    if i >= 2 && i <= 10
                        eval("legend(app.UIAxes_" + i + ", 'hide');");
                    end
                end
            end
        end
        
        function ds_update(app, idx_begin, idx_end)
            AX = app.UIAxes_1;
            START = app.Stats_1.DateStart(idx_begin);
            STOP = app.Stats_1.DateStop(idx_end) + seconds(1);
            
            % Weekday
            [~, app.WeekdayLabel.Text] = weekday(START, 'long');
            
            % Signal.
            x_axis = app.Values_1.Time(timerange(START, STOP));
            x_axis = string(x_axis, 'HH:mm:ss');
            x_axis = duration(x_axis, 'InputFormat', 'hh:mm:ss', 'Format', 'hh:mm');
            y_axis = app.Values_1.Value(timerange(START, STOP));
            plot(AX, x_axis, y_axis, 'k', 'LineWidth', 1);
            ylabel(AX, app.UnitsDropDown.Value);
            offset = AX.YLim(1);
            range = AX.YLim(2) - offset;
            ymin = offset - (0.05 * range);
            ymax = (offset + range) + (0.05 * range);
            ylim(AX, [ymin, ymax]);
            axis(AX, 'tight');
            
            % Stats.
            ds_plot_series(app, app.UIAxes_2, [idx_begin, idx_end], ["Maximum", "Mean", "Minimum"], [1, 2, 3], 0.6, true);
            ds_plot_series(app, app.UIAxes_3, [idx_begin, idx_end], ["Mode", "Median", "Mean"], [1, 3, 2], 0.6, true);
            ds_plot_series(app, app.UIAxes_5, [idx_begin, idx_end], ["Variance", "SD", "IQR"], [1, 2, 3], 0.6, true);
            ds_plot_series(app, app.UIAxes_6, [idx_begin, idx_end], ["Range", "IQR"], [4, 3], 0.6, true);
            ds_plot_series(app, app.UIAxes_7, [idx_begin, idx_end], ["Variance", "RSD"], [1, 5], 0.6, true);
            ds_plot_series(app, app.UIAxes_8, [idx_begin, idx_end], "Skewness", 1, 0.6, false);
            ds_plot_series(app, app.UIAxes_9, [idx_begin, idx_end], ["Skewness", "Kurtosis"], [1, 2], 0.6, false);
            ds_plot_series(app, app.UIAxes_10, [idx_begin, idx_end], "Kurtosis", 2, 0.6, false);
            
            % Equality.
            x_stats = string(app.Stats_1{idx_begin:idx_end, "DateStart"}, 'HH:mm');
            x_stats = duration(x_stats, 'InputFormat', 'hh:mm', 'Format', 'hh:mm');
            y_equality = app.Stats_1.Equality(idx_begin:idx_end);
            scatter(app.UIAxes_4, x_stats, y_equality, 'filled');
            ylabel(app.UIAxes_4, app.UnitsDropDown.Value);
            xlim(app.UIAxes_4, [x_stats(1), x_stats(end)]);
            ylim(app.UIAxes_4, [0, 0.06]);    
            
            % Buttons.
            is_year_first = app.YearDropDown.Value == string(app.YearDropDown.Items{1});
            is_year_last = app.YearDropDown.Value == string(app.YearDropDown.Items{end});
            is_date_first = app.DateDropDown.Value == string(app.DateDropDown.Items{end});
            is_date_last = app.DateDropDown.Value == string(app.DateDropDown.Items{1});
            
            if is_year_first && is_date_first
                app.PreviousButton.Enable = false;
                app.NextButton.Enable = true;
            elseif is_year_last && is_date_last
                app.PreviousButton.Enable = true;
                app.NextButton.Enable = false;
            else
                app.PreviousButton.Enable = true;
                app.NextButton.Enable = true;
            end
            
            app.SwitchValueChanged(app);
            
        end
        
        function dates = ds_load_dates(app)
            dates_days = string(unique(datetime(...
                app.Stats_1.DateStart.Year,...
                app.Stats_1.DateStart.Month,...
                app.Stats_1.DateStart.Day,...
                "Format", "dd. MMMM")));
            dates = ["Select"; flip(dates_days)];
        end
        
        function idx = ds_find_index(app, select_date_drop_down_object)
            selected_date = get(select_date_drop_down_object, 'Value');
            selected_date = datetime(selected_date, 'Format', 'dd. MMMM', 'InputFormat', 'dd. MMMM');
            all_dates = datetime(app.Stats_1.DateStart, 'Format', 'dd. MMMM', "InputFormat", 'dd. MMMM');
            match = ismember(string(all_dates), string(selected_date));
            index_begin = find(match, 1, 'first');
            index_end = find(match, 1, 'last');
            idx = [index_begin, index_end];
        end
        
        function ds_plot_series(app, ax, idx, y_data, color, opacity, ylabel_bool)
            x_axis = string(app.Stats_1{idx(1):idx(2), "DateStart"}, 'HH:mm');
            x_axis = duration(x_axis, 'InputFormat', 'hh:mm', 'Format', 'hh:mm');
            for i = 1:size(y_data, 2)
                y_axis = app.Stats_1{idx(1):idx(2), y_data(i)};
                plot(ax, x_axis, y_axis, 'Color', [app.Palette(color(i), :), opacity], 'LineWidth', 1);
                if i == 1
                    hold(ax, "on");
                end
            end
            if ylabel_bool
                ylabel(ax, app.UnitsDropDown.Value);
            end
            axis(ax, 'tight');
            offset = ax.YLim(1);
            range = ax.YLim(2) - offset;
            ymin = offset - (0.05 * range);
            ymax = (offset + range) + (0.05 * range);
            ylim(ax, [ymin, ymax]);
            hold(ax, "off");
        end
        
        % PLOTS OF STATISTICS (TAB_2)
        
        function pos_objects_visibility(app, input_boolean)
            OBJECTS = [
                app.YearDropDown_2;
                app.YearDropDown_2Label;
                app.DateDropDown_2;
                app.DateDropDown_2Label;
                app.PreviousButton_2;
                app.NextButton_2;
                app.UnitsDropDown_2; 
                app.UnitsDropDown_2Label;
                app.AddUnitsEditField_2; 
                app.AddUnitsEditField_2Label;
                app.Switch_2;
                app.WeekdayLabel_2;
            ];
            PLOTS = [
                app.UIAxes_11;
                app.UIAxes_12;
                app.UIAxes_13;
                app.UIAxes_14;
                app.UIAxes_15;
                app.UIAxes_16;
                app.UIAxes_17;
                app.UIAxes_18;
                app.UIAxes_19;
                app.UIAxes_20;
            ];
            set(app.AddButton_2, 'Visible', input_boolean);
            set(OBJECTS, 'Visible', input_boolean);
            set(OBJECTS, 'Enable', input_boolean);
            set(PLOTS, 'Visible', input_boolean);
        end
        
        function pos_gui_visibility(app, input_boolean)
            if ~isempty(app.UIAxes_11.Children)
                set(app.UIAxes_11.Children, 'Visible', input_boolean);
                for i = 12:20
                    eval("set(app.UIAxes_" + i + ".Children(1), 'Visible', input_boolean);");
                    eval("set(app.UIAxes_" + i + ".Children(2), 'Visible', input_boolean);");
                    eval("legend(app.UIAxes_" + i + ",'hide');");
                end
            end
        end
        
        function pos_update(app, idx_begin, idx_end)
            AX = app.UIAxes_11;
            START = app.Stats_2.DateStart(idx_begin);
            STOP = app.Stats_2.DateStop(idx_end) + seconds(1);
            
            % Weekday
            [~, app.WeekdayLabel_2.Text] = weekday(START, 'long');
            
            % Signal.
            x_axis = app.Values_2.Time(timerange(START, STOP));
            x_axis = string(x_axis, 'HH:mm:ss');
            x_axis = duration(x_axis, 'InputFormat', 'hh:mm:ss', 'Format', 'hh:mm');
            y_axis = app.Values_2.Value(timerange(START, STOP));
            
            plot(AX, x_axis, y_axis, 'k', 'LineWidth', 1);
            ylabel(AX, app.UnitsDropDown_2.Value);
            offset = AX.YLim(1);
            range = AX.YLim(2) - offset;
            ymin = offset - (0.05 * range);
            ymax = (offset + range) + (0.05 * range);
            ylim(AX, [ymin, ymax]);
            axis(AX, 'tight');
            
            % Stats.
            idx = [idx_begin, idx_end];
            pos_plot_series(app, app.UIAxes_12, idx, ["Range", "SD"], [1, 2], 0.6, true);
            pos_plot_series(app, app.UIAxes_13, idx, ["Range", "Skewness"], [1, 3], 0.6, true);
            pos_plot_series(app, app.UIAxes_14, idx, ["Range", "Kurtosis"], [1, 4], 0.6, true);
            pos_plot_series(app, app.UIAxes_15, idx, ["Range", "AccuracyRate"], [1, 5], 0.6, true);
            pos_plot_series(app, app.UIAxes_16, idx, ["SD", "Skewness"], [2, 3], 0.6, true);
            pos_plot_series(app, app.UIAxes_17, idx, ["Kurtosis", "AccuracyRate"], [4, 5], 0.6, true);
            pos_plot_series(app, app.UIAxes_18, idx, ["SD", "AccuracyRate"], [2, 5], 0.6, true);
            pos_plot_series(app, app.UIAxes_19, idx, ["Skewness", "AccuracyRate"], [3, 5], 0.6, true);
            pos_plot_series(app, app.UIAxes_20, idx, ["SD", "Kurtosis"], [2, 4], 0.6, true);
            
            % Buttons.
            is_year_first = app.YearDropDown_2.Value == string(app.YearDropDown_2.Items{1});
            is_year_last = app.YearDropDown_2.Value == string(app.YearDropDown_2.Items{end});
            is_date_first = app.DateDropDown_2.Value == string(app.DateDropDown_2.Items{end});
            is_date_last = app.DateDropDown_2.Value == string(app.DateDropDown_2.Items{1});
            
            if is_year_first && is_date_first
                app.PreviousButton_2.Enable = false;
                app.NextButton_2.Enable = true;
            elseif is_year_last && is_date_last
                app.PreviousButton_2.Enable = true;
                app.NextButton_2.Enable = false;
            else
                app.PreviousButton_2.Enable = true;
                app.NextButton_2.Enable = true;
            end
            
            Switch_2ValueChanged(app);
            
        end
        
        function dates = pos_load_dates(app)
            dates_days = string(unique(datetime(...
                app.Stats_2.DateStart.Year,...
                app.Stats_2.DateStart.Month,...
                app.Stats_2.DateStart.Day,...
                "Format", "dd. MMMM")));
            dates = ["Select"; flip(dates_days)];
        end
        
        function idx = pos_find_index(app, select_date_drop_down_object)
            selected_date = get(select_date_drop_down_object, 'Value');
            selected_date = datetime(selected_date, 'Format', 'dd. MMMM', 'InputFormat', 'dd. MMMM');
            all_dates = datetime(app.Stats_2.DateStart, 'Format', 'dd. MMMM', "InputFormat", 'dd. MMMMM');
            match = ismember(string(all_dates), string(selected_date));
            index_begin = find(match, 1, 'first');
            index_end = find(match, 1, 'last');
            idx = [index_begin, index_end];
        end
        
        function pos_plot_series(app, ax, idx, y_data, color, opacity, ylabel_bool)
            x_axis = string(app.Stats_2{idx(1):idx(2), "DateStart"}, 'HH:mm');
            x_axis = duration(x_axis, 'InputFormat', 'hh:mm', 'Format', 'hh:mm');
            for i = 1:size(y_data, 2)
                y_axis = app.Stats_2{idx(1):idx(2), y_data(i)};
                plot(ax, x_axis, y_axis, 'Color', [app.Palette(color(i), :), opacity], 'LineWidth', 1);
                if i == 1
                    hold(ax, "on");
                end
            end
            if ylabel_bool
                ylabel(ax, app.UnitsDropDown_2.Value);
            end
            axis(ax, 'tight');
            offset = ax.YLim(1);
            range = ax.YLim(2) - offset;
            ymin = offset - (0.05 * range);
            ymax = (offset + range) + (0.05 * range);
            ylim(ax, [ymin, ymax]);
            hold(ax, "off");
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, year)
            W = 1920; H = 1080;
            R = get(0, 'screensize');
            app.DescriptiveStatistic.Position = [(R(3) / 2) - (W / 2), (R(4) / 2) - (H / 2), W, H];
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            
            ds_objects_visibility(app, false);
            pos_objects_visibility(app, false);
            
            app.Years = load("years.mat", "YEARS_STRINGS");
            app.Years = app.Years.YEARS_STRINGS;
            app.YearDropDown.Items = ["Select"; app.Years];
            app.YearDropDown_2.Items = ["Select"; app.Years];
            
            app.Signal_Lengths = load("signal_lengths.mat", "SIGNAL_LENGTHS_STRINGS");
            app.Signal_Lengths = app.Signal_Lengths.SIGNAL_LENGTHS_STRINGS;
            app.SignalLengthDropDown.Items = ["Select"; app.Signal_Lengths];
            app.SignalLengthDropDown_2.Items = ["Select"; app.Signal_Lengths];
            
            app.Palette = [0, 0, 1; 1, 0, 0; 0.0431, 0.4, 0.1372; 0, 0, 0; 0.5882, 0.2941, 0;];
        end

        % Value changed function: SignalLengthDropDown
        function SignalLengthDropDownValueChanged(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            
            % Deletes the "Select" option from the list at the first selection.
            if app.SignalLengthDropDown.Items(1) == "Select"
                app.SignalLengthDropDown.Items(1) = [];
            else
                app.YearDropDown.Items = ["Select"; app.Years];
                app.YearDropDown.Value = app.YearDropDown.Items(1);
                
                ds_gui_visibility(app, false);
            end
            
            % Show DateDropDown Object.
            ds_objects_visibility(app, false);
            app.SignalLengthDropDown.Visible = true;
            app.SignalLengthDropDown.Enable = true;
            app.SignalLengthLabel.Visible = true;
            app.SignalLengthLabel.Enable = true;
            app.YearDropDown.Enable = true;
            app.YearDropDown.Visible = true;
            app.YearDropDownLabel.Enable = true;
            app.YearDropDownLabel.Visible = true;
        end

        % Value changed function: SignalLengthDropDown_2
        function SignalLengthDropDown_2ValueChanged(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            
            % Deletes the "Select" option from the list at the first selection.
            if app.SignalLengthDropDown_2.Items(1) == "Select"
                app.SignalLengthDropDown_2.Items(1) = [];
            else
                app.YearDropDown_2.Items = ["Select"; app.Years];
                app.YearDropDown_2.Value = app.YearDropDown_2.Items(1);
                
                pos_gui_visibility(app, false);
            end
            
            % Show DateDropDown Object.
            pos_objects_visibility(app, false);
            app.SignalLengthDropDown_2.Visible = true;
            app.SignalLengthDropDown_2.Enable = true;
            app.SignalLengthLabel_2.Visible = true;
            app.SignalLengthLabel_2.Enable = true;
            app.YearDropDown_2.Enable = true;
            app.YearDropDown_2.Visible = true;
            app.YearDropDown_2Label.Enable = true;
            app.YearDropDown_2Label.Visible = true;
        end

        % Value changed function: YearDropDown
        function YearDropDownValueChanged(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            app.CurrentYear = app.YearDropDown.Value;
            
            % Load Values from file.
            app.Values_1 = load("values_clean_" + app.CurrentYear + ".mat", "values_clean_" + app.CurrentYear);
            eval("app.Values_1 = app.Values_1.values_clean_" + app.CurrentYear + ";");
            
            if app.YearDropDown.Items(1) == "Select"
                app.YearDropDown.Items(1) = [];
            else
                app.DateDropDown.Items = ["Select", app.DateDropDown.Items];
                app.DateDropDown.Value = app.DateDropDown.Items(1);
                
                ds_gui_visibility(app, false);
            end
            
            % Load Stats from file.
            MINUTES = erase(app.SignalLengthDropDown.Value, " min");
            MINUTES = str2double(MINUTES);
            app.Interval_Length = seconds(minutes(MINUTES));
            app.Stats_1 = load("stats_" + app.CurrentYear + ".mat", "stats_" + MINUTES + "_" + app.CurrentYear);
            eval("app.Stats_1 = app.Stats_1.stats_" + MINUTES + "_" + app.CurrentYear + ";");
            
            % Visible Signal Length.
            ds_objects_visibility(app, false);
            app.SignalLengthDropDown.Visible = true;
            app.SignalLengthDropDown.Enable = true;
            app.SignalLengthLabel.Visible = true;
            app.SignalLengthLabel.Enable = true;
            app.YearDropDown.Enable = true;
            app.YearDropDown.Visible = true;
            app.YearDropDownLabel.Enable = true;
            app.YearDropDownLabel.Visible = true;
            app.DateDropDown.Enable = true;
            app.DateDropDown.Visible = true;
            app.DateDropDownLabel.Enable = true;
            app.DateDropDownLabel.Visible = true;
            
            % Loading list of dates into drop-down box.
            app.DateDropDown.Items = ds_load_dates(app);
            % Sets the first value from the list, which is "Select".
            app.DateDropDown.Value = app.DateDropDown.Items(1);
        end

        % Value changed function: YearDropDown_2
        function YearDropDown_2ValueChanged(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            app.CurrentYear = app.YearDropDown_2.Value;
            
            % Load Values from file.
            app.Values_2 = load("values_clean_" + app.CurrentYear + ".mat", "values_clean_" + app.CurrentYear);
            eval("app.Values_2 = app.Values_2.values_clean_" + app.CurrentYear + ";");
            
            if app.YearDropDown_2.Items(1) == "Select"
                app.YearDropDown_2.Items(1) = [];
            else
                app.DateDropDown_2.Items = ["Select", app.DateDropDown_2.Items];
                app.DateDropDown_2.Value = app.DateDropDown_2.Items(1);
                
                pos_gui_visibility(app, false);
            end
            
            % Visible Signal Length.
            pos_objects_visibility(app, false);
            app.SignalLengthDropDown_2.Visible = true;
            app.SignalLengthDropDown_2.Enable = true;
            app.SignalLengthLabel_2.Visible = true;
            app.SignalLengthLabel_2.Enable = true;
            app.YearDropDown_2.Enable = true;
            app.YearDropDown_2.Visible = true;
            app.YearDropDown_2Label.Enable = true;
            app.YearDropDown_2Label.Visible = true;
            app.DateDropDown_2.Enable = true;
            app.DateDropDown_2.Visible = true;
            app.DateDropDown_2Label.Enable = true;
            app.DateDropDown_2Label.Visible = true;
            
            % Load Stats from file.
            MINUTES = erase(app.SignalLengthDropDown_2.Value, " min");
            MINUTES = str2double(MINUTES);
            app.Interval_Length = seconds(minutes(MINUTES));
            app.Stats_2 = load("stats_" + app.CurrentYear + ".mat", "stats_" + MINUTES + "_" + app.CurrentYear);
            eval("app.Stats_2 = app.Stats_2.stats_" + MINUTES + "_" + app.CurrentYear + ";");
            
            % Loading list of dates into drop-down box.
            app.DateDropDown_2.Items = pos_load_dates(app);
            % Sets the first value from the list, which is "Select".
            app.DateDropDown_2.Value = app.DateDropDown_2.Items(1);
        end

        % Value changed function: DateDropDown
        function DateDropDownValueChanged(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            
            % This part runs once at start (when date was selected first time).
            if app.DateDropDown.Items(1) == "Select"
                app.DateDropDown.Items(1) = [];
                ds_objects_visibility(app, true);
            end
            
            Index = ds_find_index(app, app.DateDropDown);
            ds_update(app, Index(1), Index(2));
        end

        % Value changed function: DateDropDown_2
        function DateDropDown_2ValueChanged(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            
            % This part runs once at start (when date was selected first time).
            if app.DateDropDown_2.Items(1) == "Select"
                app.DateDropDown_2.Items(1) = [];
                pos_objects_visibility(app, true);
            end
            
            Index = pos_find_index(app, app.DateDropDown_2);
            pos_update(app, Index(1), Index(2));
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            
            idx_signal_length = find(ismember(app.SignalLengthDropDown.Items, app.SignalLengthDropDown.Value), 1);
            idx_year = find(ismember(app.YearDropDown.Items, app.YearDropDown.Value), 1);
            idx_date = find(ismember(app.DateDropDown.Items, app.DateDropDown.Value), 1);
            
            is_date_first = idx_date == size(app.DateDropDown.Items, 2);

            if is_date_first
                app.SignalLengthDropDown.Value = app.SignalLengthDropDown.Items(idx_signal_length);
                SignalLengthDropDownValueChanged(app);
                app.YearDropDown.Value = app.YearDropDown.Items(idx_year);
                YearDropDownValueChanged(app);
                app.DateDropDown.Value = app.DateDropDown.Items(1);
                DateDropDownValueChanged(app);
            else
                app.DateDropDown.Value = app.DateDropDown.Items(idx_date + 1);
                DateDropDownValueChanged(app);
            end
        end

        % Button pushed function: PreviousButton_2
        function PreviousButton_2Pushed(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            
            idx_signal_length = find(ismember(app.SignalLengthDropDown_2.Items, app.SignalLengthDropDown_2.Value), 1);
            idx_year = find(ismember(app.YearDropDown_2.Items, app.YearDropDown_2.Value), 1);
            idx_date = find(ismember(app.DateDropDown_2.Items, app.DateDropDown_2.Value), 1);
            
            is_date_first = idx_date == size(app.DateDropDown_2.Items, 2);

            if is_date_first
                app.SignalLengthDropDown_2.Value = app.SignalLengthDropDown_2.Items(idx_signal_length);
                SignalLengthDropDown_2ValueChanged(app);
                app.YearDropDown_2.Value = app.YearDropDown_2.Items(idx_year);
                YearDropDown_2ValueChanged(app);
                app.DateDropDown_2.Value = app.DateDropDown_2.Items(1);
                DateDropDown_2ValueChanged(app);
            else
                app.DateDropDown_2.Value = app.DateDropDown_2.Items(idx_date + 1);
                DateDropDown_2ValueChanged(app);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            
            idx_signal_length = find(ismember(app.SignalLengthDropDown.Items, app.SignalLengthDropDown.Value), 1);
            idx_year = find(ismember(app.YearDropDown.Items, app.YearDropDown.Value), 1);
            idx_date = find(ismember(app.DateDropDown.Items, app.DateDropDown.Value), 1);
            
            is_date_last = idx_date == 1;

            if is_date_last
                app.SignalLengthDropDown.Value = app.SignalLengthDropDown.Items(idx_signal_length);
                SignalLengthDropDownValueChanged(app);
                app.YearDropDown.Value = app.YearDropDown.Items(idx_year + 2);
                YearDropDownValueChanged(app);
                app.DateDropDown.Value = app.DateDropDown.Items(end);
                DateDropDownValueChanged(app);
            else
                app.DateDropDown.Value = app.DateDropDown.Items(idx_date - 1);
                DateDropDownValueChanged(app);
            end
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            
            idx_signal_length = find(ismember(app.SignalLengthDropDown_2.Items, app.SignalLengthDropDown_2.Value), 1);
            idx_year = find(ismember(app.YearDropDown_2.Items, app.YearDropDown_2.Value), 1);
            idx_date = find(ismember(app.DateDropDown_2.Items, app.DateDropDown_2.Value), 1);
            
            is_date_last = idx_date == 1;

            if is_date_last
                app.SignalLengthDropDown_2.Value = app.SignalLengthDropDown_2.Items(idx_signal_length);
                SignalLengthDropDown_2ValueChanged(app);
                app.YearDropDown_2.Value = app.YearDropDown_2.Items(idx_year + 2);
                YearDropDown_2ValueChanged(app);
                app.DateDropDown_2.Value = app.DateDropDown_2.Items(end);
                DateDropDown_2ValueChanged(app);
            else
                app.DateDropDown_2.Value = app.DateDropDown_2.Items(idx_date - 1);
                DateDropDown_2ValueChanged(app);
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            value = app.Switch.Value;
            if value == "On"
                legend(app.UIAxes_2, ["Maximum", "Mean", "Minimum"], 'Location', 'best');
                legend(app.UIAxes_3, ["Mode", "Median", "Mean"], 'Location', 'best');
                legend(app.UIAxes_5, ["Variance", "SD", "IQR"], 'Location', 'best');
                legend(app.UIAxes_6, ["Range", "IQR"], 'Location', 'best');
                legend(app.UIAxes_7, ["Variance", "RSD"], 'Location', 'best');
                legend(app.UIAxes_8, "Skewness", 'Location', 'best');
                legend(app.UIAxes_9, ["Skewness", "Kurtosis"], 'Location', 'best');
                legend(app.UIAxes_10, "Kurtosis", 'Location', 'best');
            elseif value == "Off"
                for i = [2, 3, 5, 6, 7, 8, 9, 10]
                    eval("legend(app.UIAxes_" + i + ", 'hide');");
                end
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            
            value = app.Switch_2.Value;
            if value == "On"
                legend(app.UIAxes_12, ["Range", "SD"], 'Location', 'best');
                legend(app.UIAxes_13, ["Range", "Skewness"], 'Location', 'best');
                legend(app.UIAxes_14, ["Range", "Kurtosis"], 'Location', 'best');
                legend(app.UIAxes_15, ["Range", "Accuracy Rate"], 'Location', 'best');
                legend(app.UIAxes_16, ["SD", "Skewness"], 'Location', 'best');
                legend(app.UIAxes_17, ["Kurtosis", "Accuracy Rate"], 'Location', 'best');
                legend(app.UIAxes_18, ["SD", "Accuracy Rate"], 'Location', 'best');
                legend(app.UIAxes_19, ["Skewness", "Accuracy Rate"], 'Location', 'best');
                legend(app.UIAxes_20, ["SD", "Kurtosis"], 'Location', 'best');
            elseif value == "Off"
                for i = 12:20
                    eval("legend(app.UIAxes_" + i + ", 'hide');");
                end
            end
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            for i = 1:7
                eval("app.UIAxes_" + i + ".YLabel.String = app.UnitsDropDown.Value;");
            end
        end

        % Value changed function: UnitsDropDown_2
        function UnitsDropDown_2ValueChanged(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            for i = 11:20
                eval("app.UIAxes_" + i + ".YLabel.String = app.UnitsDropDown_2.Value;");
            end
        end

        % Value changing function: AddUnitsEditField
        function AddUnitsEditFieldValueChanging(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            if size(event.Value) > 0
                app.AddButton.Enable = true;
            else
                app.AddButton.Enable = false;
            end
        end

        % Value changing function: AddUnitsEditField_2
        function AddUnitsEditField_2ValueChanging(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            if size(event.Value) > 0
                app.AddButton_2.Enable = true;
            else
                app.AddButton_2.Enable = false;
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            app.UnitsDropDown.Items = [app.UnitsDropDown.Items, app.AddUnitsEditField.Value];
            app.AddUnitsEditField.Value = "";
            app.AddButton.Enable = false;
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            uiprogressdlg(app.DescriptiveStatistic, 'Title', "Please Wait", "Indeterminate", "on");
            app.UnitsDropDown_2.Items = [app.UnitsDropDown_2.Items, app.AddUnitsEditField_2.Value];
            app.AddUnitsEditField_2.Value = "";
            app.AddButton_2.Enable = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DescriptiveStatistic = uifigure('Visible', 'off');
            app.DescriptiveStatistic.Name = 'Descriptive Statistic';
            app.TabGroup = uitabgroup(app.DescriptiveStatistic);
            app.DescriptiveStatisticTab = uitab(app.TabGroup);
            app.DescriptiveStatisticTab.Title = 'Descriptive Statistic';
            app.GridLayout = uigridlayout(app.DescriptiveStatisticTab);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.GridLayout3);
            app.UIAxes_5 = uiaxes(app.GridLayout3);
            app.UIAxes_8 = uiaxes(app.GridLayout3);
            app.UIAxes_3 = uiaxes(app.GridLayout3);
            app.UIAxes_4 = uiaxes(app.GridLayout3);
            app.UIAxes_7 = uiaxes(app.GridLayout3);
            app.UIAxes_10 = uiaxes(app.GridLayout3);
            app.UIAxes_6 = uiaxes(app.GridLayout3);
            app.UIAxes_9 = uiaxes(app.GridLayout3);
            app.UIAxes_1 = uiaxes(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.DateDropDownLabel = uilabel(app.GridLayout4);
            app.DateDropDownLabel.Text = 'Date';
            app.DateDropDown = uidropdown(app.GridLayout4);
            app.DateDropDown.Items = {};
            app.DateDropDown.ValueChangedFcn = createCallbackFcn(app, @DateDropDownValueChanged, true);
            app.DateDropDown.Value = {};
            app.PreviousButton = uibutton(app.GridLayout4, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = '◄';
            app.NextButton = uibutton(app.GridLayout4, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = '►';
            app.Switch = uiswitch(app.GridLayout4, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Tooltip = {'Show Legends.'};
            app.UnitsDropDownLabel = uilabel(app.GridLayout4);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.GridLayout4);
            app.UnitsDropDown.Items = {'U (V)', 'T (°C)', 'RH (%)'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'U (V)';
            app.AddUnitsLabel = uilabel(app.GridLayout4);
            app.AddUnitsLabel.Text = 'Add Units';
            app.AddUnitsEditField = uieditfield(app.GridLayout4, 'text');
            app.AddUnitsEditField.ValueChangingFcn = createCallbackFcn(app, @AddUnitsEditFieldValueChanging, true);
            app.AddButton = uibutton(app.GridLayout4, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add';
            app.WeekdayLabel = uilabel(app.GridLayout4);
            app.WeekdayLabel.Text = 'Weekday';
            app.YearDropDownLabel = uilabel(app.GridLayout4);
            app.YearDropDownLabel.Text = 'Year';
            app.YearDropDown = uidropdown(app.GridLayout4);
            app.YearDropDown.Items = {};
            app.YearDropDown.ValueChangedFcn = createCallbackFcn(app, @YearDropDownValueChanged, true);
            app.YearDropDown.Value = {};
            app.SignalLengthLabel = uilabel(app.GridLayout4);
            app.SignalLengthLabel.Text = 'Signal Length';
            app.SignalLengthDropDown = uidropdown(app.GridLayout4);
            app.SignalLengthDropDown.Items = {};
            app.SignalLengthDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalLengthDropDownValueChanged, true);
            app.SignalLengthDropDown.Value = {};
            app.PlotsofStatisticsTab = uitab(app.TabGroup);
            app.PlotsofStatisticsTab.Title = 'Plots of Statistics';
            app.GridLayout_2 = uigridlayout(app.PlotsofStatisticsTab);
            app.GridLayout3_2 = uigridlayout(app.GridLayout_2);
            app.UIAxes_12 = uiaxes(app.GridLayout3_2);
            app.UIAxes_13 = uiaxes(app.GridLayout3_2);
            app.UIAxes_14 = uiaxes(app.GridLayout3_2);
            app.UIAxes_15 = uiaxes(app.GridLayout3_2);
            app.UIAxes_16 = uiaxes(app.GridLayout3_2);
            app.UIAxes_17 = uiaxes(app.GridLayout3_2);
            app.UIAxes_18 = uiaxes(app.GridLayout3_2);
            app.UIAxes_19 = uiaxes(app.GridLayout3_2);
            app.UIAxes_20 = uiaxes(app.GridLayout3_2);
            app.UIAxes_11 = uiaxes(app.GridLayout_2);
            app.GridLayout4_2 = uigridlayout(app.GridLayout_2);
            app.DateDropDown_2Label = uilabel(app.GridLayout4_2);
            app.DateDropDown_2Label.Text = 'Date';
            app.DateDropDown_2 = uidropdown(app.GridLayout4_2);
            app.DateDropDown_2.Items = {};
            app.DateDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DateDropDown_2ValueChanged, true);
            app.DateDropDown_2.Value = {};
            app.PreviousButton_2 = uibutton(app.GridLayout4_2, 'push');
            app.PreviousButton_2.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_2Pushed, true);
            app.PreviousButton_2.Text = '◄';
            app.NextButton_2 = uibutton(app.GridLayout4_2, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = '►';
            app.UnitsDropDown_2Label = uilabel(app.GridLayout4_2);
            app.UnitsDropDown_2Label.Text = 'Units';
            app.UnitsDropDown_2 = uidropdown(app.GridLayout4_2);
            app.UnitsDropDown_2.Items = {'U (V)', 'T (°C)', 'RH (%)'};
            app.UnitsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDown_2ValueChanged, true);
            app.UnitsDropDown_2.Value = 'U (V)';
            app.AddUnitsEditField_2Label = uilabel(app.GridLayout4_2);
            app.AddUnitsEditField_2Label.Text = 'Add Units';
            app.AddUnitsEditField_2 = uieditfield(app.GridLayout4_2, 'text');
            app.AddUnitsEditField_2.ValueChangingFcn = createCallbackFcn(app, @AddUnitsEditField_2ValueChanging, true);
            app.AddButton_2 = uibutton(app.GridLayout4_2, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Enable = 'off';
            app.AddButton_2.Text = 'Add';
            app.WeekdayLabel_2 = uilabel(app.GridLayout4_2);
            app.WeekdayLabel_2.Text = 'Weekday';
            app.Switch_2 = uiswitch(app.GridLayout4_2, 'slider');
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Tooltip = {'Show Legends.'};
            app.YearDropDown_2Label = uilabel(app.GridLayout4_2);
            app.YearDropDown_2Label.Text = 'Year';
            app.YearDropDown_2 = uidropdown(app.GridLayout4_2);
            app.YearDropDown_2.Items = {};
            app.YearDropDown_2.ValueChangedFcn = createCallbackFcn(app, @YearDropDown_2ValueChanged, true);
            app.YearDropDown_2.Value = {};
            app.SignalLengthLabel_2 = uilabel(app.GridLayout4_2);
            app.SignalLengthLabel_2.Text = 'Signal Length';
            app.SignalLengthDropDown_2 = uidropdown(app.GridLayout4_2);
            app.SignalLengthDropDown_2.Items = {};
            app.SignalLengthDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SignalLengthDropDown_2ValueChanged, true);
            app.SignalLengthDropDown_2.Value = {};
            % Show the figure after all components are created
            app.DescriptiveStatistic.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Descriptive_Statistic(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DescriptiveStatistic)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DescriptiveStatistic)
        end
    end
end