classdef ETL_Configuration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        GridLayout3             matlab.ui.container.GridLayout
        Tlacidlo_Spat           matlab.ui.control.Button
        Tlacidlo_Dalej          matlab.ui.control.Button
        Tlacidlo_Zrusit         matlab.ui.control.Button
        Title                   matlab.ui.control.Label
        GridLayout4             matlab.ui.container.GridLayout
        Ziarovka_1              matlab.ui.control.Lamp
        Ziarovka_2              matlab.ui.control.Lamp
        Ziarovka_3              matlab.ui.control.Lamp
        Ziarovka_4              matlab.ui.control.Lamp
        Ziarovka_5              matlab.ui.control.Lamp
        Label_Vyberte_Operaciu  matlab.ui.control.Label
        List_Vyberte_Operaciu   matlab.ui.control.DropDown
        Label_Vlozte_Cestu      matlab.ui.control.Label
        Text_Vlozte_Cestu       matlab.ui.control.EditField
        Label_Krokovanie        matlab.ui.control.Label
        Numeric_Krokovanie      matlab.ui.control.NumericEditField
        Label_Jednotky          matlab.ui.control.Label
        Text_Jednotky           matlab.ui.control.EditField
        Label_Tolerancia        matlab.ui.control.Label
        Numeric_Tolerancia      matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Nastaví aplikácii šírku (W) a výšku (H) v pixeloch a umiestni
            % ju na stred obrazovky.:
            W = 640; H = 255;
            R = get(0, 'screensize');
            app.UIFigure.Position = [(R(3) / 2) - (W / 2), (R(4) / 2) - (H / 2), W, H];
            uiprogressdlg(app.UIFigure, 'Title', "Počkajte prosím...", "Indeterminate", "on");
        end

        % Button pushed function: Tlacidlo_Dalej
        function Tlacidlo_DalejButtonPushed(app, event)
            if app.Tlacidlo_Dalej.Text == "Spustiť"
                % Otvorí dialogové okno progresu:
                pd = uiprogressdlg(app.UIFigure, 'Title', "Počkajte prosím...", ...
                    "Message", "Spúšťa sa výpočet...");
                % Táto časť kódu vyberie z textových polí hodnoty a uloží
                % do následujúcich premenných:
                cesta = string(app.Text_Vlozte_Cestu.Value);
                krok = app.Numeric_Krokovanie.Value;
                jednotka = string(app.Text_Jednotky.Value);
                tolerancia = app.Numeric_Tolerancia.Value;
                % Táto časť kódu v závislosti od výberu operácie spustí operáciu:
                if app.List_Vyberte_Operaciu.Value == "ETL Update"
                    etl_update(cesta, krok, jednotka,tolerancia, pd);
                elseif app.List_Vyberte_Operaciu.Value == "ETL Append"
                    etl_append(cesta, krok, jednotka,tolerancia, pd);
                end
                % Zatvorí dialogové okno progresu:
                pd.Title = "ETL operácia bola úspešne dokončená.";
                pd.Value = 1;
                pd.Message = "";
                beep;
                pause(5);
                close(pd);
                app.Tlacidlo_Dalej.Enable = false;
            elseif app.Ziarovka_5.Visible == "on"
                % Táto časť kódu prepíše popis následujúceho kroku:
                app.Title.Text = "Krok č. 6 : Skontrolujte nastavenia pred spustením operácie.";
                % Zmení tlačidlo "Ďalej" na "Spustiť":
                app.Tlacidlo_Dalej.Text = "Spustiť";
                % Zmení farbu žiarovky na zelenú:
                app.Ziarovka_5.Color = [0, 1, 0];
                % Zablokuje úpravu tohto kroku:
                app.Label_Tolerancia.Enable = false;
                app.Numeric_Tolerancia.Enable = false;
            elseif app.Ziarovka_4.Visible == "on"
                % Táto časť kódu prepíše v závislosti od výberu operácie popis následujúceho kroku:
                if app.List_Vyberte_Operaciu.Value == "ETL Update"
                    app.Title.Text = "Krok č. 5 : Nastavte hornú hranicu počtu hodín (vrátane), ktoré majú byť doplnené.";
                elseif app.List_Vyberte_Operaciu.Value == "ETL Append"
                    app.Title.Text = "Krok č. 5 : .";
                end
                % Zmení farbu žiarovky na zelenú a odkryje nasledujúcu žiarovku:
                app.Ziarovka_4.Color = [0, 1, 0];
                app.Ziarovka_5.Visible = "on";
                % Zablokuje úpravu tohto kroku a okryje nasledujúci krok:
                app.Label_Jednotky.Enable = false;
                app.Text_Jednotky.Enable = false;
                app.Label_Tolerancia.Visible = true;
                app.Numeric_Tolerancia.Visible = true;
            elseif app.Ziarovka_3.Visible == "on"
                % Táto časť kódu prepíše v závislosti od výberu operácie popis následujúceho kroku:
                if app.List_Vyberte_Operaciu.Value == "ETL Update"
                    app.Title.Text = "Krok č. 4 : Ak je v údajoch aj jednotka meranej veličiny, uveďte ju. Ak nie, nechajte toto pole prázdne.";
                elseif app.List_Vyberte_Operaciu.Value == "ETL Append"
                    app.Title.Text = "Krok č. 4 : .";
                end
                % Zmení farbu žiarovky na zelenú a odkryje nasledujúcu žiarovku:
                app.Ziarovka_3.Color = [0, 1, 0];
                app.Ziarovka_4.Visible = "on";
                % Zablokuje úpravu tohto kroku a okryje nasledujúci krok:
                app.Label_Krokovanie.Enable = false;
                app.Numeric_Krokovanie.Enable = false;
                app.Label_Jednotky.Visible = true;
                app.Text_Jednotky.Visible = true;
            elseif app.Ziarovka_2.Visible == "on"
                % Táto časť kódu prepíše v závislosti od výberu operácie popis následujúceho kroku:
                if app.List_Vyberte_Operaciu.Value == "ETL Update"
                    app.Title.Text = "Krok č. 3 : Uveďte veľkosť krokov merania v sekundách.";
                elseif app.List_Vyberte_Operaciu.Value == "ETL Append"
                    app.Title.Text = "Krok č. 3 : .";
                end
                % Zmení farbu žiarovky na zelenú a odkryje nasledujúcu žiarovku:
                app.Ziarovka_2.Color = [0, 1, 0];
                app.Ziarovka_3.Visible = "on";
                % Zablokuje úpravu tohto kroku a okryje nasledujúci krok:
                app.Label_Vlozte_Cestu.Enable = false;
                app.Text_Vlozte_Cestu.Enable = false;
                app.Label_Krokovanie.Visible = true;
                app.Numeric_Krokovanie.Visible = true;
            elseif app.Ziarovka_1.Visible == "on"
                % Táto časť kódu prepíše v závislosti od výberu operácie popis následujúceho kroku:
                if app.List_Vyberte_Operaciu.Value == "ETL Update"
                    app.Title.Text = "Krok č. 2 : Vložte cestu k priečinku s *.CSV súbormi.";
                elseif app.List_Vyberte_Operaciu.Value == "ETL Append"
                    app.Title.Text = "Krok č. 2 : Vložte cestu k priečinku s *.CSV súbormi alebo cestu k *.CSV súboru.";
                end
                % Zmení farbu žiarovky na zelenú a odkryje nasledujúcu žiarovku:
                app.Ziarovka_1.Color = [0, 1, 0];
                app.Ziarovka_2.Visible = "on";
                % Povolí možnosť zatlačenia tlačídla Späť:
                app.Tlacidlo_Spat.Enable = true;
                % Zablokuje úpravu tohto kroku a okryje nasledujúci krok:
                app.Label_Vyberte_Operaciu.Enable = false;
                app.List_Vyberte_Operaciu.Enable = false;
                app.Label_Vlozte_Cestu.Visible = true;
                app.Text_Vlozte_Cestu.Visible = true;
            end
        end

        % Button pushed function: Tlacidlo_Spat
        function Tlacidlo_SpatButtonPushed(app, event)
            if app.Tlacidlo_Dalej.Text == "Spustiť"
                % Táto časť kódu prepíše v závislosti od výberu operácie popis predchádzajúceho kroku:
                if app.List_Vyberte_Operaciu.Value == "ETL Update"
                    app.Title.Text = "Krok č. 5 : Nastavte hornú hranicu počtu hodín (vrátane), ktoré majú byť doplnené.";
                elseif app.List_Vyberte_Operaciu.Value == "ETL Append"
                    app.Title.Text = "Krok č. 5 : .";
                end
                % Zmení tlačidlo "Spustiť" na "Ďalej":
                app.Tlacidlo_Dalej.Text = "Ďalej";
                app.Tlacidlo_Dalej.Enable = true;
                % Zmení farbu žiarovky na sivú:
                app.Ziarovka_5.Color = [0.9, 0.9, 0.9];
                % Odblokuje úpravu predchazaujúceho kroku:
                app.Label_Tolerancia.Enable = true;
                app.Numeric_Tolerancia.Enable = true;
            elseif app.Ziarovka_5.Visible == "on"
                % Táto časť kódu prepíše v závislosti od výberu operácie popis predchádzajúceho kroku:
                if app.List_Vyberte_Operaciu.Value == "ETL Update"
                    app.Title.Text = "Krok č. 4 : Ak je v údajoch aj jednotka meranej veličiny, uveďte ju. Ak nie, nechajte toto pole prázdne.";
                elseif app.List_Vyberte_Operaciu.Value == "ETL Append"
                    app.Title.Text = "Krok č. 4 : .";
                end
                % Zmení farbu predchadzajúcej žiarovky na sivú a zakryje túto žiarovku:
                app.Ziarovka_4.Color = [0.9, 0.9, 0.9];
                app.Ziarovka_5.Visible = "off";
                % Odblokuje úpravu predchazaujúceho kroku a zakryje tento krok:
                app.Label_Jednotky.Enable = true;
                app.Text_Jednotky.Enable = true;
                app.Label_Tolerancia.Visible = false;
                app.Numeric_Tolerancia.Visible = false;
            elseif app.Ziarovka_4.Visible == "on"
                % Táto časť kódu prepíše v závislosti od výberu operácie popis predchádzajúceho kroku:
                if app.List_Vyberte_Operaciu.Value == "ETL Update"
                    app.Title.Text = "Krok č. 3 : Uveďte veľkosť krokov merania v sekundách.";
                elseif app.List_Vyberte_Operaciu.Value == "ETL Append"
                    app.Title.Text = "Krok č. 3 : .";
                end
                % Zmení farbu predchadzajúcej žiarovky na sivú a zakryje túto žiarovku:
                app.Ziarovka_3.Color = [0.9, 0.9, 0.9];
                app.Ziarovka_4.Visible = "off";
                % Odblokuje úpravu predchazaujúceho kroku a zakryje tento krok:
                app.Label_Krokovanie.Enable = true;
                app.Numeric_Krokovanie.Enable = true;
                app.Label_Jednotky.Visible = false;
                app.Text_Jednotky.Visible = false;
            elseif app.Ziarovka_3.Visible == "on"
                % Táto časť kódu prepíše v závislosti od výberu operácie popis predchádzajúceho kroku:
                if app.List_Vyberte_Operaciu.Value == "ETL Update"
                    app.Title.Text = "Krok č. 2 : Vložte cestu k priečinku s *.CSV súbormi.";
                elseif app.List_Vyberte_Operaciu.Value == "ETL Append"
                    app.Title.Text = "Krok č. 2 : Vložte cestu k priečinku s *.CSV súbormi alebo cestu k *.CSV súboru.";
                end
                % Zmení farbu predchadzajúcej žiarovky na sivú a zakryje túto žiarovku:
                app.Ziarovka_2.Color = [0.9, 0.9, 0.9];
                app.Ziarovka_3.Visible = "off";
                % Odblokuje úpravu predchazaujúceho kroku a zakryje tento krok:
                app.Label_Vlozte_Cestu.Enable = true;
                app.Text_Vlozte_Cestu.Enable = true;
                app.Label_Krokovanie.Visible = false;
                app.Numeric_Krokovanie.Visible = false;
            elseif app.Ziarovka_2.Visible == "on"
                % Táto časť kódu prepíše popis predchádzajúceho kroku:
                app.Title.Text = "Krok č. 1 : Vyberte operáciu automatizovaného ETL procesu.";
                app.Tlacidlo_Spat.Enable = false;
                % Zmení farbu predchadzajúcej žiarovky na sivú a zakryje túto žiarovku:
                app.Ziarovka_1.Color = [0.9, 0.9, 0.9];
                app.Ziarovka_2.Visible = "off";
                % Odblokuje úpravu predchazaujúceho kroku a zakryje tento krok:
                app.Label_Vyberte_Operaciu.Enable = true;
                app.List_Vyberte_Operaciu.Enable = true;
                app.Label_Vlozte_Cestu.Visible = false;
                app.Text_Vlozte_Cestu.Visible = false;
            end
        end

        % Value changed function: List_Vyberte_Operaciu
        function List_Vyberte_OperaciuValueChanged(app, event)
            % Ak je v liste možnosť "Vybrať", tak túto možnosť odstráni:
            if app.List_Vyberte_Operaciu.Items(1) == "Vybrať"
                app.List_Vyberte_Operaciu.Items(1) = [];
            end
            
            % Povolí možnosť zatlačenia tlačídla Ďalej:
            app.Tlacidlo_Dalej.Enable = true;
        end

        % Button pushed function: Tlacidlo_Zrusit
        function Tlacidlo_ZrusitButtonPushed(app, event)
            % Zatvorí aplikáciu.:
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Konfigurácia automatizovaného ETL procesu';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Tlacidlo_Spat = uibutton(app.GridLayout3, 'push');
            app.Tlacidlo_Spat.ButtonPushedFcn = createCallbackFcn(app, @Tlacidlo_SpatButtonPushed, true);
            app.Tlacidlo_Spat.Enable = 'off';
            app.Tlacidlo_Spat.Text = 'Späť';
            app.Tlacidlo_Dalej = uibutton(app.GridLayout3, 'push');
            app.Tlacidlo_Dalej.ButtonPushedFcn = createCallbackFcn(app, @Tlacidlo_DalejButtonPushed, true);
            app.Tlacidlo_Dalej.Enable = 'off';
            app.Tlacidlo_Dalej.Text = 'Ďalej';
            app.Tlacidlo_Zrusit = uibutton(app.GridLayout3, 'push');
            app.Tlacidlo_Zrusit.ButtonPushedFcn = createCallbackFcn(app, @Tlacidlo_ZrusitButtonPushed, true);
            app.Tlacidlo_Zrusit.Text = 'Zrušiť';
            app.Title = uilabel(app.GridLayout);
            app.Title.Text = 'Krok č. 1 : Vyberte operáciu automatizovaného ETL procesu.';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Ziarovka_1 = uilamp(app.GridLayout4);
            app.Ziarovka_2 = uilamp(app.GridLayout4);
            app.Ziarovka_2.Visible = 'off';
            app.Ziarovka_3 = uilamp(app.GridLayout4);
            app.Ziarovka_3.Visible = 'off';
            app.Ziarovka_4 = uilamp(app.GridLayout4);
            app.Ziarovka_4.Visible = 'off';
            app.Ziarovka_5 = uilamp(app.GridLayout4);
            app.Ziarovka_5.Visible = 'off';
            app.Label_Vyberte_Operaciu = uilabel(app.GridLayout4);
            app.Label_Vyberte_Operaciu.Text = 'Vyberte operáciu';
            app.List_Vyberte_Operaciu = uidropdown(app.GridLayout4);
            app.List_Vyberte_Operaciu.Items = {'Vybrať', 'ETL Update', 'ETL Append'};
            app.List_Vyberte_Operaciu.ValueChangedFcn = createCallbackFcn(app, @List_Vyberte_OperaciuValueChanged, true);
            app.List_Vyberte_Operaciu.Value = 'Vybrať';
            app.Label_Vlozte_Cestu = uilabel(app.GridLayout4);
            app.Label_Vlozte_Cestu.Visible = 'off';
            app.Label_Vlozte_Cestu.Text = 'Vložte cestu';
            app.Text_Vlozte_Cestu = uieditfield(app.GridLayout4, 'text');
            app.Text_Vlozte_Cestu.Visible = 'off';
            app.Label_Krokovanie = uilabel(app.GridLayout4);
            app.Label_Krokovanie.Visible = 'off';
            app.Label_Krokovanie.Text = 'Krokovanie merania (s)';
            app.Numeric_Krokovanie = uieditfield(app.GridLayout4, 'numeric');
            app.Numeric_Krokovanie.Limits = [1 Inf];
            app.Numeric_Krokovanie.ValueDisplayFormat = '%.0f';
            app.Numeric_Krokovanie.Visible = 'off';
            app.Numeric_Krokovanie.Value = 1;
            app.Label_Jednotky = uilabel(app.GridLayout4);
            app.Label_Jednotky.Visible = 'off';
            app.Label_Jednotky.Text = 'Jednotky';
            app.Text_Jednotky = uieditfield(app.GridLayout4, 'text');
            app.Text_Jednotky.Visible = 'off';
            app.Label_Tolerancia = uilabel(app.GridLayout4);
            app.Label_Tolerancia.Visible = 'off';
            app.Label_Tolerancia.Text = 'Tolerancia (h)';
            app.Numeric_Tolerancia = uieditfield(app.GridLayout4, 'numeric');
            app.Numeric_Tolerancia.Limits = [0 Inf];
            app.Numeric_Tolerancia.ValueDisplayFormat = '%.0f';
            app.Numeric_Tolerancia.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ETL_Configuration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end