classdef Signals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalsUIFigure            matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout2                matlab.ui.container.GridLayout
        YearDropDownLabel          matlab.ui.control.Label
        YearDropDown               matlab.ui.control.DropDown
        DateDropDownLabel          matlab.ui.control.Label
        DateDropDown               matlab.ui.control.DropDown
        TimeDropDownLabel          matlab.ui.control.Label
        TimeDropDown               matlab.ui.control.DropDown
        PreviousButton             matlab.ui.control.Button
        NextButton                 matlab.ui.control.Button
        WeekdayLabel               matlab.ui.control.Label
        UnitsDropDownLabel         matlab.ui.control.Label
        UnitsDropDown              matlab.ui.control.DropDown
        AddUnitsLabel              matlab.ui.control.Label
        AddUnitsEditField          matlab.ui.control.EditField
        AddButton                  matlab.ui.control.Button
        ButtonGroup                matlab.ui.container.ButtonGroup
        Button_1                   matlab.ui.control.RadioButton
        Button_2                   matlab.ui.control.RadioButton
        Button_3                   matlab.ui.control.RadioButton
        SignalLengthDropDownLabel  matlab.ui.control.Label
        SignalLengthDropDown       matlab.ui.control.DropDown
        Axes_3                     matlab.ui.control.UIAxes
        Axes_4                     matlab.ui.control.UIAxes
        Axes_2                     matlab.ui.control.UIAxes
        Axes_1                     matlab.ui.control.UIAxes
    end

    % Application Properties.
    properties (Access = private)
        Values          % Časová tabuľka čistých údajov.
        Stats           % Tabuľka štatistických parametrov.
        Graphs          % Objekt, v ktorom sú umiestnené 4 grafické prvky Axes.
        Interval_Length % Dĺžka intervalov v sekundách.
        Signal_Lengths  % List dĺžok intervalov v minútach.
        Years           % List rokov.
        CurrentYear     % Zvolený rok.
        CurrentDatetime % Zvolený dátum a čas.
    end
    
    % Application Methods.
    methods (Access = private)    
        
        % Function objects_visibility.
        function objects_visibility(app, input_boolean)
            OBJECTS_VE = [
                app.YearDropDown;
                app.YearDropDownLabel;
                app.DateDropDown;
                app.DateDropDownLabel;
                app.TimeDropDown;
                app.TimeDropDownLabel;
                app.PreviousButton;
                app.NextButton;
                app.Button_1;
                app.Button_2;
                app.Button_3;
                app.UnitsDropDown; 
                app.UnitsDropDownLabel;
                app.AddUnitsEditField; 
                app.AddUnitsLabel;
                app.WeekdayLabel;
            ];
            OBJECTS_V = [
                app.AddButton;
                app.ButtonGroup;
                app.Axes_1;
                app.Axes_2;
                app.Axes_3;
                app.Axes_4;
            ];
            set(OBJECTS_VE, 'Visible', input_boolean);
            set(OBJECTS_VE, 'Enable', input_boolean);
            set(OBJECTS_V, 'Visible', input_boolean);
        end
        
        % Function load_dates.
        function dates = load_dates(app)
            dates_days = string(unique(datetime(...
                app.Stats.DateStart.Year,...
                app.Stats.DateStart.Month,...
                app.Stats.DateStart.Day,...
                'Format', "dd. MMMM")));
            dates = ["Select"; flip(dates_days)];
        end
        
        % Function load_starts.
        function starts = load_starts(app)
            % Creates a list of available times in the Stats database based on the selected date (and hence the selected signal length).
            selected_date = app.DateDropDown.Value;
            selected_date = datetime(selected_date, 'Format', 'dd. MMMM', 'InputFormat', 'dd. MMMM');
            all_dates = datetime(app.Stats.DateStart, 'Format', 'dd. MMMM', "InputFormat", 'dd. MMMMM');
            match = ismember(string(all_dates), string(selected_date));
            starts = string(app.Stats.DateStart(match), 'HH:mm');
            if selected_date == app.DateDropDown.Items{1}
                if size(starts, 1) > 3
                    starts = starts(1:(end - 3));
                else
                    starts = [];
                end
            end
            starts = ["Select"; starts];
        end
        
        % Function find_index.
        function idx = find_index(app, date_dropdown, time_dropdown)
            selected_date = get(date_dropdown, 'Value');
            selected_date = datetime(selected_date, 'Format', "dd. MMMM", 'InputFormat', "dd. MMMM");
            selected_time = get(time_dropdown, 'Value');
            selected_time = datetime(selected_time, 'Format', "HH:mm", 'InputFormat', "HH:mm");
            selected_datetime = selected_date + hours(selected_time.Hour) + minutes(selected_time.Minute);
            selected_datetime = datetime(selected_datetime, 'Format', "dd. MMMM HH:mm");
            app.CurrentDatetime = selected_datetime;
            all_dates = datetime(app.Stats.DateStart, 'Format', "dd. MMMM HH:mm", "InputFormat", "dd. MMMM HH:mm");
            match = ismember(string(all_dates), string(selected_datetime));
            idx = find(match, 1);
        end
        
        % Function update.
        function update(app, idx)
            % Weekday
            [~, app.WeekdayLabel.Text] = weekday(app.Stats.DateStart(idx), 'long');

            % Declares empty cells for graph axes.
            X = cell(1, 4);
            Y = cell(1, 4);
            
            for i = 0:3
                START = app.Stats.DateStart(idx + i);
                STOP = app.Stats.DateStop(idx + i) + seconds(1); 
                X{i + 1} = app.Values.Time(timerange(START, STOP));
                Y{i + 1} = app.Values.Value(timerange(START, STOP));
                plot(app.Graphs(i + 1), X{i + 1}, Y{i + 1});
                axis(app.Graphs(i + 1), 'tight');
            end 
            
            ButtonGroupSelectionChanged(app);
            UnitsDropDownValueChanged(app);
            
            is_year_first = app.YearDropDown.Value == string(app.YearDropDown.Items{1});
            is_year_last = app.YearDropDown.Value == string(app.YearDropDown.Items{end});
            is_date_first = app.DateDropDown.Value == string(app.DateDropDown.Items{end});
            is_date_last = app.DateDropDown.Value == string(app.DateDropDown.Items{1});
            is_time_first = app.TimeDropDown.Value == string(app.TimeDropDown.Items{1});
            is_time_last = app.TimeDropDown.Value == string(app.TimeDropDown.Items{end});
            
            if is_year_first && is_date_first && is_time_first
                app.PreviousButton.Enable = false;
                app.NextButton.Enable = true;
            elseif is_year_last && is_date_last && is_time_last
                app.PreviousButton.Enable = true;
                app.NextButton.Enable = false;
            else
                app.PreviousButton.Enable = true;
                app.NextButton.Enable = true;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            W = 1920; H = 1080;
            R = get(0, 'screensize');
            app.SignalsUIFigure.Position = [(R(3) / 2) - (W / 2), (R(4) / 2) - (H / 2), W, H];
            uiprogressdlg(app.SignalsUIFigure, 'Title', "Please Wait", "Indeterminate", "on");
            
            objects_visibility(app, false);
            
            app.Years = load("years.mat", "-mat", 'YEARS_STRINGS');
            app.Years = app.Years.YEARS_STRINGS;
            app.YearDropDown.Items = ["Select"; app.Years];
            
            app.Signal_Lengths = load("signal_lengths.mat", "SIGNAL_LENGTHS_STRINGS");
            app.Signal_Lengths = app.Signal_Lengths.SIGNAL_LENGTHS_STRINGS;
            app.SignalLengthDropDown.Items = ["Select"; app.Signal_Lengths];
            
            app.Graphs = [app.Axes_1, app.Axes_2, app.Axes_3, app.Axes_4];
        end

        % Value changed function: SignalLengthDropDown
        function SignalLengthDropDownValueChanged(app, event)
            uiprogressdlg(app.SignalsUIFigure, 'Title', "Please Wait", "Indeterminate", "on");
            
            % Deletes the "Select" option from the list at the first selection.
            if app.SignalLengthDropDown.Items(1) == "Select"
                app.SignalLengthDropDown.Items(1) = [];
            else
                if app.YearDropDown.Items(1) ~= "Select"
                    app.YearDropDown.Items = ["Select", app.YearDropDown.Items];
                    app.YearDropDown.Value = app.YearDropDown.Items(1);
                end
                
                if ~isempty(app.Axes_1.Children)
                    app.Axes_1.Children.Visible = "off";
                    app.Axes_2.Children.Visible = "off";
                    app.Axes_3.Children.Visible = "off";
                    app.Axes_4.Children.Visible = "off";
                end
            end
            
            % Show DateDropDown Object.
            objects_visibility(app, false);
            app.SignalLengthDropDown.Visible = true;
            app.SignalLengthDropDown.Enable = true;
            app.SignalLengthDropDownLabel.Visible = true;
            app.SignalLengthDropDownLabel.Enable = true;
            app.YearDropDown.Enable = true;
            app.YearDropDown.Visible = true;
            app.YearDropDownLabel.Enable = true;
            app.YearDropDownLabel.Visible = true;
        end

        % Value changed function: YearDropDown
        function YearDropDownValueChanged(app, event)
            uiprogressdlg(app.SignalsUIFigure, 'Title', "Please Wait", "Indeterminate", "on");
            
            app.CurrentYear = app.YearDropDown.Value;
            
            % Load Values from file.
            app.Values = load("values_clean_" + app.CurrentYear + ".mat", "values_clean_" + app.CurrentYear);
            eval("app.Values = app.Values.values_clean_" + app.CurrentYear + ";");
            
            % Load Stats from file.
            MINUTES = erase(app.SignalLengthDropDown.Value, " min");
            MINUTES = str2double(MINUTES);
            app.Interval_Length = seconds(minutes(MINUTES));
            app.Stats = load("stats_" + app.CurrentYear + ".mat", "stats_" + MINUTES + "_" + app.CurrentYear);
            eval("app.Stats = app.Stats.stats_" + MINUTES + "_" + app.CurrentYear + ";");
            
            if app.YearDropDown.Items(1) == "Select"
                app.YearDropDown.Items(1) = [];
            else
                if ~isempty(app.Axes_1.Children)
                    app.Axes_1.Children.Visible = "off";
                    app.Axes_2.Children.Visible = "off";
                    app.Axes_3.Children.Visible = "off";
                    app.Axes_4.Children.Visible = "off";
                end
            end
            
            % Visible Signal Length.
            objects_visibility(app, false);
            app.SignalLengthDropDown.Visible = true;
            app.SignalLengthDropDown.Enable = true;
            app.SignalLengthDropDownLabel.Visible = true;
            app.SignalLengthDropDownLabel.Enable = true;
            app.YearDropDown.Enable = true;
            app.YearDropDown.Visible = true;
            app.YearDropDownLabel.Enable = true;
            app.YearDropDownLabel.Visible = true;
            app.DateDropDown.Enable = true;
            app.DateDropDown.Visible = true;
            app.DateDropDownLabel.Enable = true;
            app.DateDropDownLabel.Visible = true;
            
            % Loading list of dates into drop-down box.
            app.DateDropDown.Items = load_dates(app);
            app.DateDropDown.Value = app.DateDropDown.Items(1);
        end

        % Value changed function: DateDropDown
        function DateDropDownValueChanged(app, event)
            uiprogressdlg(app.SignalsUIFigure, 'Title', "Please Wait", "Indeterminate", "on");
            
            if app.DateDropDown.Items(1) == "Select"
                app.DateDropDown.Items(1) = [];
            else
                if ~isempty(app.Axes_1.Children)
                    app.Axes_1.Children.Visible = "off";
                    app.Axes_2.Children.Visible = "off";
                    app.Axes_3.Children.Visible = "off";
                    app.Axes_4.Children.Visible = "off";
                end
            end
            
            % Show DateDropDown Object.
            objects_visibility(app, false);
            app.SignalLengthDropDown.Visible = true;
            app.SignalLengthDropDown.Enable = true;
            app.SignalLengthDropDownLabel.Visible = true;
            app.SignalLengthDropDownLabel.Enable = true;
            app.YearDropDown.Enable = true;
            app.YearDropDown.Visible = true;
            app.YearDropDownLabel.Enable = true;
            app.YearDropDownLabel.Visible = true;
            app.DateDropDown.Enable = true;
            app.DateDropDown.Visible = true;
            app.DateDropDownLabel.Enable = true;
            app.DateDropDownLabel.Visible = true;
            app.TimeDropDown.Enable = true;
            app.TimeDropDown.Visible = true;
            app.TimeDropDownLabel.Enable = true;
            app.TimeDropDownLabel.Visible = true;
            
            % Loading list of dates into drop-down box.
            app.TimeDropDown.Items = load_starts(app);
            app.TimeDropDown.Value = app.TimeDropDown.Items(1);
            if size(app.TimeDropDown.Items, 2) == 1
                app.DateDropDown.Items(1) = [];
                app.DateDropDown.Value = app.DateDropDown.Items(1);
                DateDropDownValueChanged(app);
            end
        end

        % Value changed function: TimeDropDown
        function TimeDropDownValueChanged(app, event)
            uiprogressdlg(app.SignalsUIFigure, 'Title', "Please Wait", "Indeterminate", "on");
            
            if app.TimeDropDown.Items(1) == "Select"
                app.TimeDropDown.Items(1) = [];
            end
            objects_visibility(app, true);
            Index = find_index(app, app.DateDropDown, app.TimeDropDown);
            update(app, Index);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            uiprogressdlg(app.SignalsUIFigure, 'Title', "Please Wait", "Indeterminate", "on");
            
            idx_year = find(ismember(app.YearDropDown.Items, app.YearDropDown.Value), 1);
            idx_date = find(ismember(app.DateDropDown.Items, app.DateDropDown.Value), 1);
            idx_time = find(ismember(app.TimeDropDown.Items, app.TimeDropDown.Value), 1);
            
            is_date_first = idx_date == size(app.DateDropDown.Items, 2);
            is_time_first = idx_time == 1;
            
            if is_time_first && is_date_first
                app.YearDropDown.Value = app.YearDropDown.Items(idx_year - 1);
                YearDropDownValueChanged(app);
                app.DateDropDown.Value = app.DateDropDown.Items(2);
                DateDropDownValueChanged(app);
                app.TimeDropDown.Value = app.TimeDropDown.Items(end);
                TimeDropDownValueChanged(app);
            elseif is_time_first && ~is_date_first
                app.DateDropDown.Value = app.DateDropDown.Items(idx_date + 1);
                DateDropDownValueChanged(app);
                app.TimeDropDown.Value = app.TimeDropDown.Items(end);
                TimeDropDownValueChanged(app);            
            elseif ~is_time_first
                app.TimeDropDown.Value = app.TimeDropDown.Items(idx_time - 1);
                TimeDropDownValueChanged(app);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            uiprogressdlg(app.SignalsUIFigure, 'Title', "Please Wait", "Indeterminate", "on");
            
            idx_year = find(ismember(app.YearDropDown.Items, app.YearDropDown.Value), 1);
            idx_date = find(ismember(app.DateDropDown.Items, app.DateDropDown.Value), 1);
            idx_time = find(ismember(app.TimeDropDown.Items, app.TimeDropDown.Value), 1);
            
            is_date_last = idx_date == 1;
            is_time_last = idx_time == size(app.TimeDropDown.Items, 2);
            
            if is_time_last && is_date_last
                app.YearDropDown.Value = app.YearDropDown.Items(idx_year + 1);
                YearDropDownValueChanged(app);
                app.DateDropDown.Value = app.DateDropDown.Items(end);
                DateDropDownValueChanged(app);
                app.TimeDropDown.Value = app.TimeDropDown.Items(2);
                TimeDropDownValueChanged(app);
            elseif is_time_last && ~is_date_last
                app.DateDropDown.Value = app.DateDropDown.Items(idx_date - 1);
                DateDropDownValueChanged(app);
                app.TimeDropDown.Value = app.TimeDropDown.Items(2);
                TimeDropDownValueChanged(app);            
            elseif ~is_time_last
                app.TimeDropDown.Value = app.TimeDropDown.Items(idx_time + 1);
                TimeDropDownValueChanged(app);
            end
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            uiprogressdlg(app.SignalsUIFigure, 'Title', "Please Wait", "Indeterminate", "on");
            
            for i = 1:4
                app.Graphs(i).YLabel.String = app.UnitsDropDown.Value;
            end
        end

        % Value changing function: AddUnitsEditField
        function AddUnitsEditFieldValueChanging(app, event)
            uiprogressdlg(app.SignalsUIFigure, 'Title', "Please Wait", "Indeterminate", "on");
            
            if size(event.Value) > 0
                app.AddButton.Enable = 1;
            else
                app.AddButton.Enable = 0;
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            uiprogressdlg(app.SignalsUIFigure, 'Title', "Please Wait", "Indeterminate", "on");
            
            app.UnitsDropDown.Items = [app.UnitsDropDown.Items app.AddUnitsEditField.Value];
            app.AddUnitsEditField.Value = "";
            app.AddButton.Enable = 0;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            uiprogressdlg(app.SignalsUIFigure, 'Title', "Please Wait", "Indeterminate", "on");
            
            selectedButton = app.ButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "1" % The y-axis boundaries of the graphs are set according to the minimum and maximum values related to the measurement day.
                    idx = find_index(app, app.DateDropDown, app.TimeDropDown); 
                    for i = 1:4
                        Index = app.Stats.DateStart.Day == app.CurrentDatetime.Day & ...
                                app.Stats.DateStart.Month == app.CurrentDatetime.Month;
                        if nnz(find(Index) == idx - 1 + i)
                            YLim_Minimum = min(app.Stats.Minimum(Index));
                            YLim_Maximum = max(app.Stats.Maximum(Index));
                            app.Graphs(i).YLim = [YLim_Minimum - 0.1, YLim_Maximum + 0.1];
                        else
                            Index = app.Stats.DateStart.Day == app.CurrentDatetime.Day + 1 & ...
                                    app.Stats.DateStart.Month == app.CurrentDatetime.Month;
                            YLim_Minimum = min(app.Stats.Minimum(Index));
                            YLim_Maximum = max(app.Stats.Maximum(Index));
                            app.Graphs(i).YLim = [YLim_Minimum - 0.1, YLim_Maximum + 0.1];
                        end
                    end
                case "2" % The y-axis boundaries of the graphs are set according to the minimum and maximum values related to the specific graph.
                    for i = 1:4
                        axis(app.Graphs(i), "tight");
                        ylim(app.Graphs(i), [app.Graphs(i).YLim(1) - 0.1, app.Graphs(i).YLim(2) + 0.1]);
                    end
                case "3" % The y-axis boundaries of the graphs are set according to the minimum and maximum values of the current selection.
                    % Reset y-axis limits.
                    YLims = cell(4, 1);
                    for i = 1:4
                        ylim(app.Graphs(i), "auto");
                        YLims{i} = app.Graphs(i).YLim;
                    end
                    YLims = cell2mat(YLims);
                    YLim_Minimum = min(YLims(:, 1));
                    YLim_Maximum = max(YLims(:, 2));
                    for i = 1:4
                        app.Graphs(i).YLim = [YLim_Minimum - 0.1, YLim_Maximum + 0.1];
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalsUIFigure = uifigure('Visible', 'off');
            app.SignalsUIFigure.Name = 'Signals';
            app.GridLayout = uigridlayout(app.SignalsUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.YearDropDownLabel = uilabel(app.GridLayout2);
            app.YearDropDownLabel.Text = 'Year';
            app.YearDropDown = uidropdown(app.GridLayout2);
            app.YearDropDown.Items = {};
            app.YearDropDown.ValueChangedFcn = createCallbackFcn(app, @YearDropDownValueChanged, true);
            app.YearDropDown.Value = {};
            app.DateDropDownLabel = uilabel(app.GridLayout2);
            app.DateDropDownLabel.Text = 'Date';
            app.DateDropDown = uidropdown(app.GridLayout2);
            app.DateDropDown.Items = {};
            app.DateDropDown.ValueChangedFcn = createCallbackFcn(app, @DateDropDownValueChanged, true);
            app.DateDropDown.Value = {};
            app.TimeDropDownLabel = uilabel(app.GridLayout2);
            app.TimeDropDownLabel.Text = 'Time';
            app.TimeDropDown = uidropdown(app.GridLayout2);
            app.TimeDropDown.Items = {};
            app.TimeDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeDropDownValueChanged, true);
            app.TimeDropDown.Value = {};
            app.PreviousButton = uibutton(app.GridLayout2, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = '◄';
            app.NextButton = uibutton(app.GridLayout2, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = '►';
            app.WeekdayLabel = uilabel(app.GridLayout2);
            app.WeekdayLabel.Text = 'Weekday';
            app.UnitsDropDownLabel = uilabel(app.GridLayout2);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.GridLayout2);
            app.UnitsDropDown.Items = {'U (V)', 'T (°C)', 'RH (%)'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'U (V)';
            app.AddUnitsLabel = uilabel(app.GridLayout2);
            app.AddUnitsLabel.Text = 'Add Units';
            app.AddUnitsEditField = uieditfield(app.GridLayout2, 'text');
            app.AddUnitsEditField.ValueChangingFcn = createCallbackFcn(app, @AddUnitsEditFieldValueChanging, true);
            app.AddButton = uibutton(app.GridLayout2, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add';
            app.ButtonGroup = uibuttongroup(app.GridLayout2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Tooltip = {'View Mode'; '1 = The y-axis boundaries of the graphs are set according to the minimum and maximum values related to the measurement day.'; '2 = The y-axis boundaries of the graphs are set according to the minimum and maximum values related to the specific graph.'; '3 = The y-axis boundaries of the graphs are set according to the minimum and maximum values of the current selection.'};
            app.Button_1 = uiradiobutton(app.ButtonGroup);
            app.Button_1.Text = '1';
            app.Button_1.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '3';
            app.SignalLengthDropDownLabel = uilabel(app.GridLayout2);
            app.SignalLengthDropDownLabel.Text = 'Signal Length';
            app.SignalLengthDropDown = uidropdown(app.GridLayout2);
            app.SignalLengthDropDown.Items = {};
            app.SignalLengthDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalLengthDropDownValueChanged, true);
            app.SignalLengthDropDown.Value = {};
            app.Axes_3 = uiaxes(app.GridLayout);
            title(app.Axes_3, '')
            xlabel(app.Axes_3, '')
            ylabel(app.Axes_3, '')
            app.Axes_4 = uiaxes(app.GridLayout);
            title(app.Axes_4, '')
            xlabel(app.Axes_4, '')
            ylabel(app.Axes_4, '')
            app.Axes_2 = uiaxes(app.GridLayout);
            title(app.Axes_2, '')
            xlabel(app.Axes_2, '')
            ylabel(app.Axes_2, '')
            app.Axes_1 = uiaxes(app.GridLayout);
            title(app.Axes_1, '')
            xlabel(app.Axes_1, '')
            ylabel(app.Axes_1, '')
            % Show the figure after all components are created
            app.SignalsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Signals

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalsUIFigure)
        end
    end
end