classdef Statistics_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Statistics_AnalysisUIapp  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout2               matlab.ui.container.GridLayout
        GridLayout3               matlab.ui.container.GridLayout
        Axes_PDF                  matlab.ui.control.UIAxes
        Axes_CDF                  matlab.ui.control.UIAxes
        Axes_PP_Plot              matlab.ui.control.UIAxes
        StatsTable                matlab.ui.control.Table
        GridLayout4               matlab.ui.container.GridLayout
        SignalLengthLabel         matlab.ui.control.Label
        SignalLengthDropDown      matlab.ui.control.DropDown
        YearDropDownLabel         matlab.ui.control.Label
        YearDropDown              matlab.ui.control.DropDown
        DateDropDownLabel         matlab.ui.control.Label
        DateDropDown              matlab.ui.control.DropDown
        TimeDropDownLabel         matlab.ui.control.Label
        TimeDropDown              matlab.ui.control.DropDown
        PreviousButton            matlab.ui.control.Button
        NextButton                matlab.ui.control.Button
        UnitsDropDownLabel        matlab.ui.control.Label
        UnitsDropDown             matlab.ui.control.DropDown
        AddUnitsLabel             matlab.ui.control.Label
        AddUnitsEditField         matlab.ui.control.EditField
        AddButton                 matlab.ui.control.Button
        WeekdayLabel              matlab.ui.control.Label
        Axes_Signal               matlab.ui.control.UIAxes
    end

    % Vlastnosti aplikácie.:
    properties (Access = private)
        Values          % Časová tabuľka čistých údajov.
        Stats           % Tabuľka štatistických parametrov.
        Interval_Length % Dĺžka intervalov v sekundách.
        Signal_Lengths  % List dĺžok intervalov v minútach.
        Years           % List rokov.
        CurrentYear     % Zvolený rok.
        CurrentDatetime % Zvolený dátum a čas.
    end
    
    % Application Methods.
    methods (Access = private)
        
        % Function objects_visibility.
        function objects_visibility(app, input_boolean)
            OBJECTS_VE = [
                app.YearDropDown;
                app.YearDropDownLabel;
                app.DateDropDown;
                app.DateDropDownLabel;
                app.TimeDropDown;
                app.TimeDropDownLabel;
                app.PreviousButton;
                app.NextButton;
                app.UnitsDropDown; 
                app.UnitsDropDownLabel;
                app.AddUnitsEditField; 
                app.AddUnitsLabel;
                app.WeekdayLabel;
            ];
            OBJECTS_V = [
                app.AddButton;
                app.Axes_Signal;
                app.Axes_CDF;
                app.Axes_PDF;
                app.Axes_PP_Plot;
            ];
            set(OBJECTS_VE, 'Visible', input_boolean);
            set(OBJECTS_VE, 'Enable', input_boolean);
            set(OBJECTS_V, 'Visible', input_boolean);
            if input_boolean == true
                set(app.StatsTable, 'Visible', 'on');
                set(app.StatsTable, 'Enable', 'on');
            else
                set(app.StatsTable, 'Visible', 'off');
                set(app.StatsTable, 'Enable', 'off');
            end
        end
        
        % Function gui_visibility.
        function gui_visibility(app, input_boolean)
            if ~isempty(app.Axes_Signal.Children)
                set(app.Axes_Signal.Children, 'Visible', input_boolean);
                set(app.Axes_CDF.Children, 'Visible', input_boolean);
                set(app.Axes_PDF.Children, 'Visible', input_boolean);
                set(app.Axes_PP_Plot.Children, 'Visible', input_boolean);
            end
            if input_boolean == true
                set(app.StatsTable, 'Visible', 'on');
                set(app.StatsTable, 'Enable', 'on');
            else
                set(app.StatsTable, 'Visible', 'off');
                set(app.StatsTable, 'Enable', 'off');
            end
        end
        
        % Function load_dates.
        function dates = load_dates(app)
            dates_days = string(unique(datetime(...
                app.Stats.DateStart.Year,...
                app.Stats.DateStart.Month,...
                app.Stats.DateStart.Day,...
                "Format", "dd. MMMM")));
            dates = ["Select"; flip(dates_days)];
        end
    
        % Function load_starts
        function starts = load_starts(app)
            % Creates a list of available times in the Stats database based on the selected date (and hence the selected signal length).
            selected_date = app.DateDropDown.Value;
            selected_date = datetime(selected_date, 'Format', 'dd. MMMM', 'InputFormat', 'dd. MMMM');
            all_dates = datetime(app.Stats.DateStart, 'Format', 'dd. MMMM', "InputFormat", 'dd. MMMMM');
            match = ismember(string(all_dates), string(selected_date));
            starts = string(app.Stats.DateStart(match), 'HH:mm');
            starts = ["Select"; starts];
        end
    
        % Function find_index.
        function idx = find_index(app, date_dropdown, time_dropdown)
            selected_date = get(date_dropdown, 'Value');
            selected_date = datetime(selected_date, 'Format', "dd. MMMM", 'InputFormat', "dd. MMMM");
            selected_time = get(time_dropdown, 'Value');
            selected_time = datetime(selected_time, 'Format', "HH:mm", 'InputFormat', "HH:mm");
            selected_datetime = selected_date + hours(selected_time.Hour) + minutes(selected_time.Minute);
            selected_datetime = datetime(selected_datetime, 'Format', "dd. MMMM HH:mm");
            app.CurrentDatetime = selected_datetime;
            all_dates = datetime(app.Stats.DateStart, 'Format', "dd. MMMM HH:mm", "InputFormat", "dd. MMMM HH:mm");
            match = ismember(string(all_dates), string(selected_datetime));
            idx = find(match, 1);
        end
        
        % Function update.
        function update(app, idx)
            % Weekday
            [~, app.WeekdayLabel.Text] = weekday(app.Stats.DateStart(idx), 'long');
            
            START = app.Stats.DateStart(idx);
            STOP = app.Stats.DateStop(idx) + seconds(1);
            INTERVAL = timerange(START, STOP);
            MIN = app.Stats.Minimum(idx);
            MAX = app.Stats.Maximum(idx);
            
            % Sets the horizontal axis step of the signal graph according to the selected signal length.
            app.StatsTable.Data = rows2vars(app.Stats(idx, :));
            
            % This part of the code ensures that the Probability Density Function (PDF) chart is plotted correctly.
            x1 = MIN:0.1:MAX;
            mu = app.Stats.Mean(idx);
            sigma = app.Stats.SD(idx);
            y1 = pdf("Normal", x1, mu, sigma);
            plot(app.Axes_PDF, x1, y1);
            hold(app.Axes_PDF, 'on');
            scatter(app.Axes_PDF, x1, y1, 'blue');
            hold(app.Axes_PDF, 'off');
            axis(app.Axes_PDF, "tight");
            
            % This part of the code ensures that the Cumulative Distribution Function (CDF) chart is plotted correctly.
            x2 = MIN:0.1:MAX;
            mu = app.Stats.Mean(idx);
            sigma = app.Stats.SD(idx);
            y2 = cdf("Normal", x2, mu, sigma);
            plot(app.Axes_CDF, x2, y2);
            hold(app.Axes_CDF, 'on');
            scatter(app.Axes_CDF, x2, y2, 'blue');
            hold(app.Axes_CDF, 'off');
            axis(app.Axes_CDF, "tight");
            
            % This part of the code ensures correct plotting of the signal graph.
            X = app.Values.Time(INTERVAL);
            Y = app.Values.Value(INTERVAL);
            plot(app.Axes_Signal, X, Y);
            axis(app.Axes_Signal, 'tight');
            ylim(app.Axes_Signal, [app.Axes_Signal.YLim(1) - 0.1, app.Axes_Signal.YLim(2) + 0.1]);
            app.Axes_Signal.XLim = [START, STOP];
            
            % This part of the code ensures that the graph of the examined probabilities are plotted correctly.
            a = table();
            a.probs = (0:0.001:0.999)';
            a.z_input = (a.probs + 1) / 2;
            a.z = norminv(a.z_input);
            a.left = mu - (a.z * sigma);
            a.right = mu + (a.z * sigma);
            a.inside = sum(Y >= a.left' & Y <= a.right')';
            a.inside_perc = (a.inside / app.Interval_Length) * 100;
            x1_res = a.probs * 100;
            y1_res = a.probs * 100;
            x2_res = a.probs * 100;
            y2_res = a.inside_perc;
            plot(app.Axes_PP_Plot, x1_res, y1_res, "black");
            hold(app.Axes_PP_Plot, "on");
            scatter(app.Axes_PP_Plot, x2_res, y2_res, 10, "red", "filled", "MarkerFaceAlpha", 0.33, "MarkerEdgeAlpha", 0.33);
            axis(app.Axes_PP_Plot, "square");
            grid(app.Axes_PP_Plot, "on");
            hold(app.Axes_PP_Plot, "off");
            
            UnitsDropDownValueChanged(app);
            
            % Disables the Next or Stop buttons when the first or last sample is currently displayed in the application.
            is_year_first = app.YearDropDown.Value == string(app.YearDropDown.Items{1});
            is_year_last = app.YearDropDown.Value == string(app.YearDropDown.Items{end});
            is_date_first = app.DateDropDown.Value == string(app.DateDropDown.Items{end});
            is_date_last = app.DateDropDown.Value == string(app.DateDropDown.Items{1});
            is_time_first = app.TimeDropDown.Value == string(app.TimeDropDown.Items{1});
            is_time_last = app.TimeDropDown.Value == string(app.TimeDropDown.Items{end});
            
            if is_year_first && is_date_first && is_time_first
                app.PreviousButton.Enable = false;
                app.NextButton.Enable = true;
            elseif is_year_last && is_date_last && is_time_last
                app.PreviousButton.Enable = true;
                app.NextButton.Enable = false;
            else
                app.PreviousButton.Enable = true;
                app.NextButton.Enable = true;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            W = 1920; H = 1080;
            R = get(0, 'screensize');
            app.Statistics_AnalysisUIapp.Position = [(R(3) / 2) - (W / 2), (R(4) / 2) - (H / 2), W, H];
            uiprogressdlg(app.Statistics_AnalysisUIapp, 'Title', "Please Wait", "Indeterminate", "on");
            
            objects_visibility(app, false);
            
            app.Years = load("years.mat", "-mat", 'YEARS_STRINGS');
            app.Years = app.Years.YEARS_STRINGS;
            app.YearDropDown.Items = ["Select"; app.Years];
            
            app.Signal_Lengths = load("signal_lengths.mat", "SIGNAL_LENGTHS_STRINGS");
            app.Signal_Lengths = app.Signal_Lengths.SIGNAL_LENGTHS_STRINGS;
            app.SignalLengthDropDown.Items = ["Select"; app.Signal_Lengths];
        end

        % Value changed function: SignalLengthDropDown
        function SignalLengthDropDownValueChanged(app, event)
            uiprogressdlg(app.Statistics_AnalysisUIapp, 'Title', "Please Wait", "Indeterminate", "on");
            
            % Deletes the "Select" option from the list at the first selection.
            if app.SignalLengthDropDown.Items(1) == "Select"
                app.SignalLengthDropDown.Items(1) = [];
            else
                app.YearDropDown.Items = ["Select"; app.Years];
                app.YearDropDown.Value = app.YearDropDown.Items(1);
                
                gui_visibility(app, false);
            end
            
            % Show DateDropDown Object.
            objects_visibility(app, false);
            app.SignalLengthDropDown.Visible = true;
            app.SignalLengthDropDown.Enable = true;
            app.SignalLengthLabel.Visible = true;
            app.SignalLengthLabel.Enable = true;
            app.YearDropDown.Enable = true;
            app.YearDropDown.Visible = true;
            app.YearDropDownLabel.Enable = true;
            app.YearDropDownLabel.Visible = true;
        end

        % Value changed function: YearDropDown
        function YearDropDownValueChanged(app, event)
            uiprogressdlg(app.Statistics_AnalysisUIapp, 'Title', "Please Wait", "Indeterminate", "on");
            app.CurrentYear = app.YearDropDown.Value;
            
            % Load Values from file.
            app.Values = load("values_clean_" + app.CurrentYear + ".mat", "values_clean_" + app.CurrentYear);
            eval("app.Values = app.Values.values_clean_" + app.CurrentYear + ";");
            
            if app.YearDropDown.Items(1) == "Select"
                app.YearDropDown.Items(1) = [];
            else
                app.DateDropDown.Items = ["Select", app.DateDropDown.Items];
                app.DateDropDown.Value = app.DateDropDown.Items(1);
                
                gui_visibility(app, false);
            end
            
            % Load Stats from file.
            MINUTES = erase(app.SignalLengthDropDown.Value, " min");
            MINUTES = str2double(MINUTES);
            app.Interval_Length = seconds(minutes(MINUTES));
            app.Stats = load("stats_" + app.CurrentYear + ".mat", "stats_" + MINUTES + "_" + app.CurrentYear);
            eval("app.Stats = app.Stats.stats_" + MINUTES + "_" + app.CurrentYear + ";");          
            
            % Visible Signal Length.
            objects_visibility(app, false);
            app.SignalLengthDropDown.Visible = true;
            app.SignalLengthDropDown.Enable = true;
            app.SignalLengthLabel.Visible = true;
            app.SignalLengthLabel.Enable = true;
            app.YearDropDown.Enable = true;
            app.YearDropDown.Visible = true;
            app.YearDropDownLabel.Enable = true;
            app.YearDropDownLabel.Visible = true;
            app.DateDropDown.Enable = true;
            app.DateDropDown.Visible = true;
            app.DateDropDownLabel.Enable = true;
            app.DateDropDownLabel.Visible = true;
            
            % Loading list of dates into drop-down box.
            app.DateDropDown.Items = load_dates(app);
            % Sets the first value from the list, which is "Select".
            app.DateDropDown.Value = app.DateDropDown.Items(1);
        end

        % Value changed function: DateDropDown
        function DateDropDownValueChanged(app, event)
            uiprogressdlg(app.Statistics_AnalysisUIapp, 'Title', "Please Wait", "Indeterminate", "on");
            
            if app.DateDropDown.Items(1) == "Select"
                app.DateDropDown.Items(1) = [];
            else
                app.TimeDropDown.Items = ["Select", app.TimeDropDown.Items];
                app.TimeDropDown.Value = app.TimeDropDown.Items(1);
                
                gui_visibility(app, false);
            end
            
            % Show DateDropDown Object.
            objects_visibility(app, false);
            app.SignalLengthDropDown.Visible = true;
            app.SignalLengthDropDown.Enable = true;
            app.SignalLengthLabel.Visible = true;
            app.SignalLengthLabel.Enable = true;
            app.YearDropDown.Enable = true;
            app.YearDropDown.Visible = true;
            app.YearDropDownLabel.Enable = true;
            app.YearDropDownLabel.Visible = true;
            app.DateDropDown.Enable = true;
            app.DateDropDown.Visible = true;
            app.DateDropDownLabel.Enable = true;
            app.DateDropDownLabel.Visible = true;
            app.TimeDropDown.Enable = true;
            app.TimeDropDown.Visible = true;
            app.TimeDropDownLabel.Enable = true;
            app.TimeDropDownLabel.Visible = true;
            
            % Loading list of dates into drop-down box.
            app.TimeDropDown.Items = load_starts(app);
            app.TimeDropDown.Value = app.TimeDropDown.Items(1);
        end

        % Value changed function: TimeDropDown
        function TimeDropDownValueChanged(app, event)
            uiprogressdlg(app.Statistics_AnalysisUIapp, 'Title', "Please Wait", "Indeterminate", "on");
            
            if app.TimeDropDown.Items(1) == "Select"
                app.TimeDropDown.Items(1) = [];
            end
            
            objects_visibility(app, true);
            
            Index = find_index(app, app.DateDropDown, app.TimeDropDown);
            update(app, Index);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            uiprogressdlg(app.Statistics_AnalysisUIapp, 'Title', "Please Wait", "Indeterminate", "on");
            
            idx_year = find(ismember(app.YearDropDown.Items, app.YearDropDown.Value), 1);
            idx_date = find(ismember(app.DateDropDown.Items, app.DateDropDown.Value), 1);
            idx_time = find(ismember(app.TimeDropDown.Items, app.TimeDropDown.Value), 1);
            
            is_date_first = idx_date == size(app.DateDropDown.Items, 2);
            is_time_first = idx_time == 1;
            
            if is_time_first && is_date_first
                app.YearDropDown.Value = app.YearDropDown.Items(idx_year - 1);
                YearDropDownValueChanged(app);
                app.DateDropDown.Value = app.DateDropDown.Items(2);
                DateDropDownValueChanged(app);
                app.TimeDropDown.Value = app.TimeDropDown.Items(end);
                TimeDropDownValueChanged(app);
            elseif is_time_first && ~is_date_first
                app.DateDropDown.Value = app.DateDropDown.Items(idx_date + 1);
                DateDropDownValueChanged(app);
                app.TimeDropDown.Value = app.TimeDropDown.Items(end);
                TimeDropDownValueChanged(app);            
            elseif ~is_time_first
                app.TimeDropDown.Value = app.TimeDropDown.Items(idx_time - 1);
                TimeDropDownValueChanged(app);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            uiprogressdlg(app.Statistics_AnalysisUIapp, 'Title', "Please Wait", "Indeterminate", "on");
            
            idx_year = find(ismember(app.YearDropDown.Items, app.YearDropDown.Value), 1);
            idx_date = find(ismember(app.DateDropDown.Items, app.DateDropDown.Value), 1);
            idx_time = find(ismember(app.TimeDropDown.Items, app.TimeDropDown.Value), 1);
            
            is_date_last = idx_date == 1;
            is_time_last = idx_time == size(app.TimeDropDown.Items, 2);
            
            if is_time_last && is_date_last
                app.YearDropDown.Value = app.YearDropDown.Items(idx_year + 1);
                YearDropDownValueChanged(app);
                app.DateDropDown.Value = app.DateDropDown.Items(end);
                DateDropDownValueChanged(app);
                app.TimeDropDown.Value = app.TimeDropDown.Items(2);
                TimeDropDownValueChanged(app);
            elseif is_time_last && ~is_date_last
                app.DateDropDown.Value = app.DateDropDown.Items(idx_date - 1);
                DateDropDownValueChanged(app);
                app.TimeDropDown.Value = app.TimeDropDown.Items(2);
                TimeDropDownValueChanged(app);            
            elseif ~is_time_last
                app.TimeDropDown.Value = app.TimeDropDown.Items(idx_time + 1);
                TimeDropDownValueChanged(app);
            end
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            uiprogressdlg(app.Statistics_AnalysisUIapp, 'Title', "Please Wait", "Indeterminate", "on");
            
            value = app.UnitsDropDown.Value;
            app.Axes_Signal.YLabel.String = value;
            app.Axes_PDF.XLabel.String = value;
            app.Axes_CDF.XLabel.String = value;
        end

        % Value changing function: AddUnitsEditField
        function AddUnitsEditFieldValueChanging(app, event)
            uiprogressdlg(app.Statistics_AnalysisUIapp, 'Title', "Please Wait", "Indeterminate", "on");
            
            if size(event.Value) > 0
                app.AddButton.Enable = 1;
            else
                app.AddButton.Enable = 0;
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            uiprogressdlg(app.Statistics_AnalysisUIapp, 'Title', "Please Wait", "Indeterminate", "on");
            
            app.UnitsDropDown.Items = [app.UnitsDropDown.Items, app.AddUnitsEditField.Value];
            app.AddUnitsEditField.Value = "";
            app.AddButton.Enable = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Statistics_AnalysisUIapp = uifigure('Visible', 'off');
            app.Statistics_AnalysisUIapp.Name = 'Statistics';
            app.GridLayout = uigridlayout(app.Statistics_AnalysisUIapp);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Axes_PDF = uiaxes(app.GridLayout3);
            title(app.Axes_PDF, 'Probability Density Function')
            xlabel(app.Axes_PDF, 'Voltage (V)')
            ylabel(app.Axes_PDF, 'Density (%)')
            app.Axes_CDF = uiaxes(app.GridLayout3);
            title(app.Axes_CDF, 'Cumulative Distribution Function')
            xlabel(app.Axes_CDF, 'Voltage (V)')
            ylabel(app.Axes_CDF, 'Probability (%)')
            app.Axes_PP_Plot = uiaxes(app.GridLayout2);
            title(app.Axes_PP_Plot, 'Actual over estimated observations')
            xlabel(app.Axes_PP_Plot, 'Relative estimated observations (%)')
            ylabel(app.Axes_PP_Plot, 'Relative actual observations (%)')
            app.StatsTable = uitable(app.GridLayout2);
            app.StatsTable.ColumnName = {'Name'; 'Value'};
            app.StatsTable.RowName = {};
            app.StatsTable.Enable = 'off';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.SignalLengthLabel = uilabel(app.GridLayout4);
            app.SignalLengthLabel.Text = 'Signal Length';
            app.SignalLengthDropDown = uidropdown(app.GridLayout4);
            app.SignalLengthDropDown.Items = {};
            app.SignalLengthDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalLengthDropDownValueChanged, true);
            app.SignalLengthDropDown.Value = {};
            app.YearDropDownLabel = uilabel(app.GridLayout4);
            app.YearDropDownLabel.Text = 'Year';
            app.YearDropDown = uidropdown(app.GridLayout4);
            app.YearDropDown.Items = {};
            app.YearDropDown.ValueChangedFcn = createCallbackFcn(app, @YearDropDownValueChanged, true);
            app.YearDropDown.Value = {};
            app.DateDropDownLabel = uilabel(app.GridLayout4);
            app.DateDropDownLabel.Text = 'Date';
            app.DateDropDown = uidropdown(app.GridLayout4);
            app.DateDropDown.Items = {};
            app.DateDropDown.ValueChangedFcn = createCallbackFcn(app, @DateDropDownValueChanged, true);
            app.DateDropDown.Value = {};
            app.TimeDropDownLabel = uilabel(app.GridLayout4);
            app.TimeDropDownLabel.Text = 'Time';
            app.TimeDropDown = uidropdown(app.GridLayout4);
            app.TimeDropDown.Items = {};
            app.TimeDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeDropDownValueChanged, true);
            app.TimeDropDown.Value = {};
            app.PreviousButton = uibutton(app.GridLayout4, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = '◄';
            app.NextButton = uibutton(app.GridLayout4, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = '►';
            app.UnitsDropDownLabel = uilabel(app.GridLayout4);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.GridLayout4);
            app.UnitsDropDown.Items = {'U (V)', 'T (°C)', 'RH (%)'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'U (V)';
            app.AddUnitsLabel = uilabel(app.GridLayout4);
            app.AddUnitsLabel.Text = 'Add Units';
            app.AddUnitsEditField = uieditfield(app.GridLayout4, 'text');
            app.AddUnitsEditField.ValueChangingFcn = createCallbackFcn(app, @AddUnitsEditFieldValueChanging, true);
            app.AddButton = uibutton(app.GridLayout4, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add';
            app.WeekdayLabel = uilabel(app.GridLayout4);
            app.WeekdayLabel.Text = 'Weekday';
            app.Axes_Signal = uiaxes(app.GridLayout);
            title(app.Axes_Signal, 'Signal')
            xlabel(app.Axes_Signal, '')
            ylabel(app.Axes_Signal, 'Voltage (V)')
            % Show the figure after all components are created
            app.Statistics_AnalysisUIapp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Statistics_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Statistics_AnalysisUIapp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Statistics_AnalysisUIapp)
        end
    end
end